classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DealerStandingUI              matlab.ui.control.NumericEditField
        DealerStandingEditFieldLabel  matlab.ui.control.Label
        PlayerStandingUI              matlab.ui.control.NumericEditField
        PlayerStandingEditFieldLabel  matlab.ui.control.Label
        DealerTotalUI                 matlab.ui.control.NumericEditField
        DealerTotalEditFieldLabel     matlab.ui.control.Label
        PlayerTotalUI                 matlab.ui.control.NumericEditField
        PlayerTotalEditFieldLabel     matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        DiceValueButtonGroup          matlab.ui.container.ButtonGroup
        BottomDiceButton              matlab.ui.control.RadioButton
        TopDiceButton                 matlab.ui.control.RadioButton
        SumofDiceButton               matlab.ui.control.RadioButton
        StartButton                   matlab.ui.control.Button
        RoleButtonGroup               matlab.ui.container.ButtonGroup
        DealerButton                  matlab.ui.control.RadioButton
        PlayerButton                  matlab.ui.control.RadioButton
        StandButton                   matlab.ui.control.Button
        HitButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        channelID = 2976444 % ThingSpeak Channel ID
        writeKey = '7D4W0J88D5Z76I9N' % Write Key
        readKey = 'GX2EPQWYTSLNY04C'% Read Key
        myPlayerNum % 1 for player, 2 for dealer
        otherPlayerNum 
        writeDelay = 5 % seconds to pause for Thingspeak write
        readDelay = 1 % seconds to pause for Thingspeak read
        dice1 % Roll for dice 1 (top)
        dice2 % Roll for dice 2 (bottom)
        playerTotal % player total score; field 1
        dealerTotal % dealer total score; field 2
        playerStanding % player standing score; field 3
        dealerStanding % dealer standing score; field 4
        whoseTurn % 1 for player, 2 for dealer; field 5
        action % hit (1) or stand (0); field 6
        
    end
    
    methods (Access = private)
        
        function [] = ClearThingSpeakChannel(app)
            % Clears fields at start of new game
            pause(app.writeDelay)
            thingSpeakWrite(app.channelID,'Fields',[1,2,3,4,5,6],'Values',[0,0,0,0,0,0],'WriteKey',app.writeKey);
            
        end
        
        function [] = SendDataToOtherPlayer(app)
            % Uploads all data to ThinkSpeak
            pause(app.writeDelay)
            thingSpeakWrite(app.channelID,'Fields',[1,2,3,4,5,6],'Values',...
                [app.playerTotal,app.dealerTotal,app.playerStanding,app.dealerStanding,...
                app.whoseTurn,app.action],'WriteKey',app.writeKey);
        end
        
        function [] = WaitForOtherPlayer(app)
            
            while app.myPlayerNum ~= app.whoseTurn
                % read data table from channel
                dataTable = thingSpeakRead(app.channelID,'Fields',[1,2,3,4,5,6],'ReadKey',...
                    app.readKey,'OutputFormat','table');
                
                app.playerTotal = dataTable{end,2};
                app.dealerTotal = dataTable{end,3};
                app.playerStanding = dataTable{end,4};
                app.dealerStanding = dataTable{end,5};
                app.whoseTurn = dataTable{end,6};
                app.action = dataTable{end,7};

                pause(app.readDelay)
            end

        end
        
        function results = UpdateStatus(app)
            app.PlayerTotalUI.Value = app.playerTotal;
            app.DealerTotalUI.Value = app.dealerTotal;
            app.PlayerStandingUI.Value = app.playerStanding;
            app.DealerStandingUI.Value = app.dealerStanding;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.HitButton,'Enable','off');
            set(app.StandButton,'Enable','off');
            set(app.SumofDiceButton,'Enable','off');
            set(app.TopDiceButton,'Enable','off');
            set(app.BottomDiceButton,'Enable','off');
            set(app.SelectButton,'Enable','off');
            
            app.ClearThingSpeakChannel();
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Choosing Roles
            if app.RoleButtonGroup.SelectedObject.Text == 'Player'
                app.myPlayerNum = 1;
                app.otherPlayerNum = 2;
            else
                app.myPlayerNum = 2;
                app.otherPlayerNum = 1;
            end

            % Initializing Values
            app.playerTotal = 0;
            app.dealerTotal = 0;
            app.playerStanding = 0;
            app.dealerStanding = 0;
            app.whoseTurn = 2;

            set(app.DealerButton,'Enable','off');
            set(app.PlayerButton,'Enable','off');
            set(app.StartButton,'Enable','off');
            set(app.SelectButton,'Enable','off');

            if app.myPlayerNum == 2
                set(app.HitButton,'Enable','on');
            else
                app.WaitForOtherPlayer()
                app.UpdateStatus()
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.DiceValueButtonGroup.SelectedObject.Text = 'Sum of Dice';
            set(app.SelectButton,'Enable','on');
            app.action = 1;
            app.dice1 = randi(6);
            app.dice2 = randi(6);
            if (app.myPlayerNum == 2 && app.dealerStanding >= 16) | ...
                    (app.myPlayerNum == 1 & app.playerStanding >= 16)
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','on');
                set(app.BottomDiceButton,'Enable','on');
            end
                
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            set(app.SelectButton,'Enable','off');

            if app.myPlayerNum == 2
                switch app.DiceValueButtonGroup.SelectedObject.Text
                    case 'Sum of Dice'
                        app.dealerStanding = app.dealerStanding + app.dice1 + app.dice2;
                    case 'Top Dice'
                        app.dealerStanding = app.dealerStanding + app.dice1;
                    case 'Bottom Dice'
                        app.dealerStanding = app.dealerStanding + app.dice2;
                end

                app.UpdateStatus()

                if app.dealerStanding >= 17
                    set(app.StandButton,'Enable','on');
                end

                SendDataToOtherPlayer(app);
            else
                set(app.StandButton,'Enable','on');
                switch app.DiceValueButtonGroup.SelectedObject.Text
                    case 'Sum of Dice'
                        app.playerStanding = app.playerStanding + app.dice1 + app.dice2;
                    case 'Top Dice'
                        app.playerStanding = app.playerStanding + app.dice1;
                    case 'Bottom Dice'
                        app.playerStanding = app.playerStanding + app.dice2;
                end
                app.UpdateStatus()
                SendDataToOtherPlayer(app);
            end
       
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            if app.whoseTurn == 1
                app.whoseTurn == 2;
            else
                app.whoseTurn == 1;
            end

            set(app.SelectButton,'Enable','off');
            set(app.SumofDiceButton,'Enable','off');
            set(app.TopDiceButton,'Enable','off');
            set(app.BottomDiceButton,'Enable','off');
            set(app.HitButton,'Enable','off');
            set(app.StandButton,'Enable','off');

            app.action = 0;

            app.SendDataToOtherPlayer()

            app.WaitForOtherPlayer()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.RoleButtonGroup = uibuttongroup(app.UIFigure);
            app.RoleButtonGroup.Title = 'Role';
            app.PlayerButton = uiradiobutton(app.RoleButtonGroup);
            app.PlayerButton.Text = 'Player';
            app.PlayerButton.Value = true;
            app.DealerButton = uiradiobutton(app.RoleButtonGroup);
            app.DealerButton.Text = 'Dealer';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DiceValueButtonGroup = uibuttongroup(app.UIFigure);
            app.DiceValueButtonGroup.Title = 'Dice Value';
            app.SumofDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.SumofDiceButton.Text = 'Sum of Dice';
            app.SumofDiceButton.Value = true;
            app.TopDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.TopDiceButton.Text = 'Top Dice';
            app.BottomDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.BottomDiceButton.Text = 'Bottom Dice';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PlayerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerTotalEditFieldLabel.Text = 'Player Total';
            app.PlayerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.DealerTotalEditFieldLabel.Text = 'Dealer Total';
            app.DealerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerStandingEditFieldLabel.Text = 'Player Standing';
            app.PlayerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.DealerStandingEditFieldLabel.Text = 'Dealer Standing';
            app.DealerStandingUI = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end