classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        dice2_6                       matlab.ui.control.Image
        dice2_5                       matlab.ui.control.Image
        dice2_4                       matlab.ui.control.Image
        dice2_3                       matlab.ui.control.Image
        dice2_2                       matlab.ui.control.Image
        dice2_1                       matlab.ui.control.Image
        dice1_6                       matlab.ui.control.Image
        dice1_5                       matlab.ui.control.Image
        dice1_4                       matlab.ui.control.Image
        dice1_3                       matlab.ui.control.Image
        dice1_2                       matlab.ui.control.Image
        dice1_1                       matlab.ui.control.Image
        RoundWinnerLabel              matlab.ui.control.Label
        MessageLabel                  matlab.ui.control.Label
        Dice2Label                    matlab.ui.control.Label
        Dice1Label                    matlab.ui.control.Label
        NewRoundButton                matlab.ui.control.Button
        NewGameButton                 matlab.ui.control.Button
        DealerStandingUI              matlab.ui.control.NumericEditField
        DealerStandingEditFieldLabel  matlab.ui.control.Label
        PlayerStandingUI              matlab.ui.control.NumericEditField
        PlayerStandingEditFieldLabel  matlab.ui.control.Label
        DealerTotalUI                 matlab.ui.control.NumericEditField
        DealerTotalEditFieldLabel     matlab.ui.control.Label
        PlayerTotalUI                 matlab.ui.control.NumericEditField
        PlayerTotalEditFieldLabel     matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        DiceValueButtonGroup          matlab.ui.container.ButtonGroup
        BottomDiceButton              matlab.ui.control.RadioButton
        TopDiceButton                 matlab.ui.control.RadioButton
        SumofDiceButton               matlab.ui.control.RadioButton
        StartButton                   matlab.ui.control.Button
        RoleButtonGroup               matlab.ui.container.ButtonGroup
        DealerButton                  matlab.ui.control.RadioButton
        PlayerButton                  matlab.ui.control.RadioButton
        StandButton                   matlab.ui.control.Button
        HitButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        % ThingSpeak Channel ID and Keys (Placeholders, replace with actual)
        channelID = 2976444; % ThingSpeak Channel ID
        writeKey = '7D4W0J88D5Z76I9N'; % Write Key
        readKey = 'GX2EPQWYTSLNY04C'; % Read Key
        
        myPlayerNum % 1 for player, 2 for dealer
        
        % Game state variables (now aligned with ThingSpeak fields)
        dice1 % Roll for dice 1 (local, not sent via ThingSpeak)
        dice2 % Roll for dice 2 (local, not sent via ThingSpeak)
        
        % ThingSpeak Fields (max 7 fields)
        playerStanding_TS % Field 1: Player's current round score
        dealerStanding_TS % Field 2: Dealer's current round score
        playerGameScore_TS % Field 3: Player's overall game score (still named _TS for ThingSpeak)
        dealerGameScore_TS % Field 4: Dealer's overall game score (still named _TS for ThingSpeak)
        turnAndAction_TS % Field 5: Encoded (whoseTurn * 10 + action)
        gameStatus_TS % Field 6: Encoded (gameEnded * 1 + overallGameEnded * 2)
        playerStoodAndDealerStood_TS % Field 7: Encoded (playerStood * 1 + dealerStood * 2)
        diceValues_TS % Field 8: 10 * dice1 + dice2
        
        % Local game state variables (derived from ThingSpeak or local only)
        whoseTurn % 1 for player, 2 for dealer
        action % hit (1) or stand (0)
        gameEnded % true if current round is over
        overallGameEnded % true if entire game is over
        playerStood % true if player has stood
        dealerStood % true if dealer has stood
        
        % Delays for ThingSpeak operations (adjust as needed)
        writeDelay = 15; % seconds to pause for ThingSpeak write
        readDelay = 0.5; % seconds to pause for ThingSpeak read
        
    end
    
    methods (Access = private)
        
        function [] = ClearThingSpeakChannel(app)
            % Clears fields at start of new game
            pause(app.writeDelay)
            thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', zeros(1,8), 'WriteKey', app.writeKey);
            fprintf('ThingSpeak channel cleared.\n');
            
        end
        
        function SendDataToOtherPlayer(app)
            % Encode turn and action
            app.turnAndAction_TS = (app.whoseTurn * 10) + app.action;
            
            % Encode game status
            app.gameStatus_TS = (app.gameEnded * 1) + (app.overallGameEnded * 2);

            % Encode player stood status
            playerStoodVal = double(app.playerStood); % Convert logical to double (0 or 1)
            dealerStoodVal = double(app.dealerStood); % Convert logical to double (0 or 1)
            app.playerStoodAndDealerStood_TS = (playerStoodVal * 1) + (dealerStoodVal * 2);

            pause(app.writeDelay); % Pause before writing
            thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', ...
                [app.playerStanding_TS, app.dealerStanding_TS, app.playerGameScore_TS, ...
                app.dealerGameScore_TS, app.turnAndAction_TS, app.gameStatus_TS, ...
                app.playerStoodAndDealerStood_TS,app.diceValues_TS], 'WriteKey', app.writeKey);
            fprintf('Data sent to ThingSpeak.\n');
        end
        
        function WaitForOtherPlayer(app)
            app.MessageLabel.Text = 'Waiting for the other player...';
            app.setControlsEnabled(false); % Disable controls while waiting
            
            while true % Loop until it's my turn or game ends
                dataTable = thingSpeakRead(app.channelID, 'Fields', 1:8, 'ReadKey', app.readKey, 'OutputFormat', 'table');
                
                if isempty(dataTable) || height(dataTable) < 1
                    pause(app.readDelay);
                    continue;
                end
                
                % Update local app properties from the latest ThingSpeak entry
                app.playerStanding_TS = dataTable{end,2};
                app.dealerStanding_TS = dataTable{end,3};
                app.playerGameScore_TS = dataTable{end,4};
                app.dealerGameScore_TS = dataTable{end,5};
                app.turnAndAction_TS = dataTable{end,6};
                app.gameStatus_TS = dataTable{end,7};
                app.playerStoodAndDealerStood_TS = dataTable{end,8}; 
                app.diceValues_TS = dataTable{end,9};
                
                % Decode turn and action
                app.whoseTurn = floor(app.turnAndAction_TS / 10);
                app.action = mod(app.turnAndAction_TS, 10);
                
                % Decode game status - Reverting logical() cast
                app.overallGameEnded = (app.gameStatus_TS >= 2); 
                app.gameEnded = (mod(app.gameStatus_TS, 2) == 1 || app.overallGameEnded);
                
                % Decode player stood status - Reverting logical() cast
                app.playerStood = (mod(app.playerStoodAndDealerStood_TS, 2) == 1);
                app.dealerStood = (app.playerStoodAndDealerStood_TS >= 2);

                % Decode dice values
                app.dice1 = floor(app.diceValues_TS / 10);
                app.dice2 = app.diceValues_TS - 10 * app.dice1;
                
                app.UpdateStatus(); % Update UI
                
                if app.overallGameEnded
                    app.handleOverallGameEnd();
                    return;
                end
                
                % Check for round end condition: both stood OR someone busted
                if (app.playerStood && app.dealerStood) || (app.playerStanding_TS > 21) || (app.dealerStanding_TS > 21)
                    app.gameEnded = true; % Force gameEnded to true if conditions met
                    app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
                    return; % Exit loop if round ended
                end

                if app.myPlayerNum == app.whoseTurn && ~app.gameEnded
                    app.MessageLabel.Text = 'It''s your turn! Click Hit or Stand.';
                    app.setControlsEnabled(true); % Enable controls for current player's turn
                    break; % Exit loop if it's my turn and game is not ended
                end
                
                pause(app.readDelay);
            end
        end

        function InitialRetrieveData(app)
            pause(app.writeDelay + 1);
            dataTable = thingSpeakRead(app.channelID, 'Fields', 1:8, 'ReadKey', app.readKey, 'OutputFormat', 'table');
                while app.dealerStanding_TS == 0
                    if isempty(dataTable) || height(dataTable) < 1
                        pause(app.readDelay);
                    end
                
                    % Update local app properties from the latest ThingSpeak entry
                    app.playerStanding_TS = dataTable{end,2};
                    app.dealerStanding_TS = dataTable{end,3};
                    app.playerGameScore_TS = dataTable{end,4};
                    app.dealerGameScore_TS = dataTable{end,5};
                    app.turnAndAction_TS = dataTable{end,6};
                    app.gameStatus_TS = dataTable{end,7};
                    app.playerStoodAndDealerStood_TS = dataTable{end,8}; 
                    app.diceValues_TS = dataTable{end,9};
                
                    % Decode turn and action
                    app.whoseTurn = floor(app.turnAndAction_TS / 10);
                    app.action = mod(app.turnAndAction_TS, 10);
                
                    % Decode game status - Reverting logical() cast
                    app.overallGameEnded = (app.gameStatus_TS >= 2); 
                    app.gameEnded = (mod(app.gameStatus_TS, 2) == 1 || app.overallGameEnded);
                
                    % Decode player stood status - Reverting logical() cast
                    app.playerStood = (mod(app.playerStoodAndDealerStood_TS, 2) == 1);
                    app.dealerStood = (app.playerStoodAndDealerStood_TS >= 2);

                    % Decode dice values
                    app.dice1 = floor(app.diceValues_TS / 10);
                    app.dice2 = app.diceValues_TS - 10 * app.dice1;
                    
                    app.UpdateStatus(); % Update UI
                end
        end
        
        function UpdateStatus(app)
            app.PlayerStandingUI.Value = app.playerStanding_TS;
            app.DealerStandingUI.Value = app.dealerStanding_TS;
            app.PlayerTotalUI.Value = app.playerGameScore_TS; % Renamed
            app.DealerTotalUI.Value = app.dealerGameScore_TS; % Renamed
        end

        function setControlsEnabled(app, enable)
            set(app.HitButton, 'Enable', enable);
            set(app.StandButton, 'Enable', enable);
            set(app.SelectButton, 'Enable', 'off'); % Select button always off until Hit
            set(app.SumofDiceButton, 'Enable', 'off');
            set(app.TopDiceButton, 'Enable', 'off');
            set(app.BottomDiceButton, 'Enable', 'off');
            set(app.NewRoundButton, 'Enable', 'off');
            set(app.NewGameButton, 'Enable', 'off');

            % Reverting logical() casts in conditions
            if app.gameEnded & ~app.overallGameEnded
                set(app.NewRoundButton, 'Enable', 'on');
                set(app.HitButton, 'Enable', 'off');
                set(app.StandButton, 'Enable', 'off');
            elseif app.overallGameEnded
                set(app.NewGameButton, 'Enable', 'on');
                set(app.HitButton, 'Enable', 'off');
                set(app.StandButton, 'Enable', 'off');
            end
        end

        function [roll1, roll2] = performDiceRoll(app)
            roll1 = randi(6);
            roll2 = randi(6);
            app.dice1 = roll1;
            app.dice2 = roll2;
            app.Dice1Label.Text = sprintf('Dice 1: %d', roll1);
            app.Dice2Label.Text = sprintf('Dice 2: %d', roll2);
            app.diceValues_TS = 10 * roll1 + roll2;
        end

        function [newStanding] = applyDiceValue(app, currentStanding, r1, r2, selection)
            newStanding = currentStanding;

            switch selection
                case 'Sum of Dice'
                    newStanding = newStanding + (r1 + r2);
                case 'Top Dice'
                    newStanding = newStanding + r1;
                case 'Bottom Dice'
                    newStanding = newStanding + r2;
            end
        end

        function determineRoundWinner(app, pStanding, dStanding)
            winnerMessage = '';
            
            % Determine if either player busted
            playerBusted = pStanding > 21;
            dealerBusted = dStanding > 21;

            if playerBusted && dealerBusted
                winnerMessage = 'Both busted! It''s a tie. No points awarded.';
            elseif playerBusted
                winnerMessage = 'Player busted! Dealer wins the round.';
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
            elseif dealerBusted
                winnerMessage = 'Dealer busted! Player wins the round.';
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
            elseif pStanding == dStanding
                winnerMessage = 'It''s a tie! No points awarded.';
            elseif pStanding > dStanding
                winnerMessage = 'Player wins the round!';
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
            else
                winnerMessage = 'Dealer wins the round!';
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
            end
            
            app.RoundWinnerLabel.Text = winnerMessage;
            app.MessageLabel.Text = 'Round Over!';
            app.UpdateStatus(); % Update scores immediately on UI

            % Check for overall game winner
            if app.playerGameScore_TS >= 5 || app.dealerGameScore_TS >= 5
                app.overallGameEnded = true;
                app.handleOverallGameEnd();
            else
                app.gameEnded = true; % Mark round as ended
                app.setControlsEnabled(false); % Disable controls
                set(app.NewRoundButton, 'Enable', 'on'); % Enable new round button
                app.SendDataToOtherPlayer(); % Send final round state
            end
        end

        function handleOverallGameEnd(app)
            app.setControlsEnabled(false); % Disable all game controls
            set(app.NewGameButton, 'Enable', 'on'); % Enable new game button
            app.RoundWinnerLabel.Text = ''; % Clear round winner
            if app.playerGameScore_TS >= 5
                app.MessageLabel.Text = 'Game Over! Player wins the game!';
            elseif app.dealerGameScore_TS >= 5
                app.MessageLabel.Text = 'Game Over! Dealer wins the game!';
            end
            app.SendDataToOtherPlayer(); % Send final game state
        end

        function startNewRound(app)
            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.whoseTurn = 2; % Dealer starts
            app.action = 0;
            app.dice1 = 0;
            app.dice2 = 0;
            app.RoundWinnerLabel.Text = '';
            app.MessageLabel.Text = 'Starting a new round! Waiting for dealer''s initial roll.';
            app.gameEnded = false;
            app.overallGameEnded = false;
            app.playerStood = false; % Reset stood status
            app.dealerStood = false; % Reset stood status
            app.UpdateStatus();
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            
            app.setControlsEnabled(false); % Disable all controls initially

            % If I am the Dealer, perform initial roll for the new round
            if app.myPlayerNum == 2
                app.MessageLabel.Text = 'It''s your turn (Dealer)! Performing initial roll...';
                [roll1, roll2] = app.performDiceRoll();
                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice');
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus();
                app.MessageLabel.Text = sprintf('It''s your turn (Dealer)! You rolled an initial %d and %d (Sum: %d). Click Hit or Stand.', roll1, roll2, newStanding);
            else
                app.WaitForOtherPlayer(); % Wait for dealer's initial roll
            end
            app.SendDataToOtherPlayer(); % Send initial round state
        end

        function resetGame(app)
            app.playerGameScore_TS = 0;
            app.dealerGameScore_TS = 0;
            app.startNewRound(); % This will reset round scores and send data
            app.MessageLabel.Text = 'Welcome! Select your role to start.';
            set(app.RoleButtonGroup, 'Visible', 'on'); % Show role selection
            set(app.StartButton, 'Visible', 'on'); % Show start button
            set(app.NewGameButton, 'Enable', 'off'); % Disable new game button
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setControlsEnabled(false);
            set(app.NewRoundButton, 'Visible', 'off'); % Hide until needed
            set(app.NewGameButton, 'Visible', 'off'); % Hide until needed
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';

            % Clear ThingSpeak channel on startup
            app.ClearThingSpeakChannel();
            app.UpdateStatus(); % Initialize UI with zeros
            app.MessageLabel.Text = 'Welcome! Select your role to start.';
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Choosing Roles
            if strcmp(app.RoleButtonGroup.SelectedObject.Text, 'Player')
                app.myPlayerNum = 1;
            else % Dealer
                app.myPlayerNum = 2;
            end

            % Initializing ThingSpeak properties
            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.playerGameScore_TS = 0;
            app.dealerGameScore_TS = 0;
            app.whoseTurn = 1; % Dealer starts
            app.action = 0;
            app.gameEnded = false;
            app.overallGameEnded = false;
            app.playerStood = false; % Initialize stood status
            app.dealerStood = false; % Initialize stood status
            
            set(app.RoleButtonGroup, 'Visible', 'off'); % Hide role selection
            set(app.StartButton, 'Visible', 'off'); % Hide start button
            set(app.NewRoundButton, 'Visible', 'on'); % Make new round button visible
            set(app.NewGameButton, 'Visible', 'on'); % Make new game button visible

            app.UpdateStatus(); % Update UI with initial zeros

            % Perform initial dealer roll if I am the dealer
            if app.myPlayerNum == 2
                app.MessageLabel.Text = 'It''s your turn (Dealer)! Performing initial roll...';
                [roll1, roll2] = app.performDiceRoll();
                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice');
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus(); % Update UI with dealer's initial roll
                app.MessageLabel.Text = sprintf('It''s your turn (Dealer)! You rolled an initial %d and %d (Sum: %d). Click Hit or Stand.', roll1, roll2, newStanding);
                app.SendDataToOtherPlayer();
                app.WaitForOtherPlayer();
            else
                app.MessageLabel.Text = 'Waiting for Dealer''s initial roll...';
                app.InitialRetrieveData();
                app.setControlsEnabled(true); % Disable controls for player initially
            end
            app.SendDataToOtherPlayer(); % Send initial game state
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; % Do nothing if game/round ended or not my turn
            end

            [roll1, roll2] = app.performDiceRoll();
            app.action = 1; % Set action to Hit
            
            % Human player: enable dice selection
            set(app.SelectButton, 'Enable', 'on');
            set(app.HitButton, 'Enable', 'off'); % Disable hit until selection made
            set(app.StandButton, 'Enable', 'off'); % Disable stand until selection made
            
            % Enable dice selection radio buttons if standing is 16 or higher
            currentStanding = app.playerStanding_TS; % For player
            if app.myPlayerNum == 2 % If I am dealer, check dealer standing
                currentStanding = app.dealerStanding_TS;
            end

            if currentStanding >= 16
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','on');
                set(app.BottomDiceButton,'Enable','on');
            else
                % If standing is less than 16, only sum of dice is allowed
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','off');
                set(app.BottomDiceButton,'Enable','off');
                app.DiceValueButtonGroup.SelectedObject = app.SumofDiceButton; % Auto-select sum
            end
            app.MessageLabel.Text = sprintf('You rolled a %d and a %d. Choose which value to use.', roll1, roll2);
            app.SendDataToOtherPlayer(); % Send updated state (action=1, dice rolled)
                
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; % Do nothing if game/round ended or not my turn
            end

            set(app.SelectButton,'Enable','off');
            set(app.SumofDiceButton,'Enable','off');
            set(app.TopDiceButton,'Enable','off');
            set(app.BottomDiceButton,'Enable','off');
            
            currentSelection = app.DiceValueButtonGroup.SelectedObject.Text;

            if app.myPlayerNum == 1 % Player's turn
                [newStanding] = app.applyDiceValue(app.playerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.playerStanding_TS = newStanding;
            else % Dealer's turn (human-controlled)
                [newStanding] = app.applyDiceValue(app.dealerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.dealerStanding_TS = newStanding;
            end
            
            app.UpdateStatus(); % Update UI with new scores

            % Check for bust/21 immediately after selection
            gameIsOver = false;
            if (app.myPlayerNum == 1 && app.playerStanding_TS > 21) || (app.myPlayerNum == 2 && app.dealerStanding_TS > 21)
                gameIsOver = true;
            end

            if gameIsOver
                app.gameEnded = true;
                app.MessageLabel.Text = 'You busted! Round ending...';
                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
            else
                app.MessageLabel.Text = 'Score updated. You can Hit again or Stand.';
                app.setControlsEnabled(true); % Re-enable Hit/Stand
            end
            app.SendDataToOtherPlayer(); % Send updated state
       
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; % Do nothing if game/round ended or not my turn
            end

            app.action = 0; % Set action to Stand
            
            % Set player's stood status
            if app.myPlayerNum == 1
                app.playerStood = true;
            else % myPlayerNum == 2 (Dealer)
                app.dealerStood = true;
            end

            % Switch turns
            if app.whoseTurn == 1
                app.whoseTurn = 2;
            else
                app.whoseTurn = 1;
            end
            
            app.setControlsEnabled(false); % Disable controls
            set(app.SelectButton,'Enable','off'); % Ensure select is off
            set(app.SumofDiceButton,'Enable','off');
            set(app.TopDiceButton,'Enable','off');
            set(app.BottomDiceButton,'Enable','off');
            
            app.MessageLabel.Text = 'You stood. Waiting for other player...';
            
            app.SendDataToOtherPlayer(); % Send updated state
            
            % Check if both players have stood or someone busted to end round
            if (app.playerStood && app.dealerStood) || (app.playerStanding_TS > 21) || (app.dealerStanding_TS > 21)
                app.gameEnded = true;
                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
            else
                app.WaitForOtherPlayer(); % Wait for other player's turn
            end
        end

        % Button pushed function: NewRoundButton
        function NewRoundButtonPushed(app, event)
            app.startNewRound();
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.resetGame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.RoleButtonGroup = uibuttongroup(app.UIFigure);
            app.RoleButtonGroup.Title = 'Role';
            app.PlayerButton = uiradiobutton(app.RoleButtonGroup);
            app.PlayerButton.Text = 'Player';
            app.PlayerButton.Value = true;
            app.DealerButton = uiradiobutton(app.RoleButtonGroup);
            app.DealerButton.Text = 'Dealer';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DiceValueButtonGroup = uibuttongroup(app.UIFigure);
            app.DiceValueButtonGroup.Title = 'Dice Value';
            app.SumofDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.SumofDiceButton.Text = 'Sum of Dice';
            app.SumofDiceButton.Value = true;
            app.TopDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.TopDiceButton.Text = 'Top Dice';
            app.BottomDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.BottomDiceButton.Text = 'Bottom Dice';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PlayerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerTotalEditFieldLabel.Text = 'Player Total';
            app.PlayerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerTotalUI.AllowEmpty = 'on';
            app.DealerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.DealerTotalEditFieldLabel.Text = 'Dealer Total';
            app.DealerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerTotalUI.AllowEmpty = 'on';
            app.PlayerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerStandingEditFieldLabel.Text = 'Player Standing';
            app.PlayerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerStandingUI.AllowEmpty = 'on';
            app.DealerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.DealerStandingEditFieldLabel.Text = 'Dealer Standing';
            app.DealerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerStandingUI.AllowEmpty = 'on';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.NewRoundButton = uibutton(app.UIFigure, 'push');
            app.NewRoundButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoundButtonPushed, true);
            app.NewRoundButton.Text = 'New Round';
            app.Dice1Label = uilabel(app.UIFigure);
            app.Dice1Label.Text = 'Dice1';
            app.Dice2Label = uilabel(app.UIFigure);
            app.Dice2Label.Text = 'Dice2';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages';
            app.RoundWinnerLabel = uilabel(app.UIFigure);
            app.RoundWinnerLabel.Text = 'Round Winner';
            app.dice1_1 = uiimage(app.UIFigure);
            app.dice1_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice1_2 = uiimage(app.UIFigure);
            app.dice1_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice1_3 = uiimage(app.UIFigure);
            app.dice1_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice1_4 = uiimage(app.UIFigure);
            app.dice1_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice1_5 = uiimage(app.UIFigure);
            app.dice1_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice1_6 = uiimage(app.UIFigure);
            app.dice1_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            app.dice2_1 = uiimage(app.UIFigure);
            app.dice2_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice2_2 = uiimage(app.UIFigure);
            app.dice2_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice2_3 = uiimage(app.UIFigure);
            app.dice2_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice2_4 = uiimage(app.UIFigure);
            app.dice2_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice2_5 = uiimage(app.UIFigure);
            app.dice2_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice2_6 = uiimage(app.UIFigure);
            app.dice2_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end