classdef testV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        dice2_6                       matlab.ui.control.Image
        dice2_5                       matlab.ui.control.Image
        dice2_4                       matlab.ui.control.Image
        dice2_3                       matlab.ui.control.Image
        dice2_2                       matlab.ui.control.Image
        dice2_1                       matlab.ui.control.Image
        dice1_6                       matlab.ui.control.Image
        dice1_5                       matlab.ui.control.Image
        dice1_4                       matlab.ui.control.Image
        dice1_3                       matlab.ui.control.Image
        dice1_2                       matlab.ui.control.Image
        dice1_1                       matlab.ui.control.Image
        RoundWinnerLabel              matlab.ui.control.Label
        MessageLabel                  matlab.ui.control.Label
        Dice2Label                    matlab.ui.control.Label
        Dice1Label                    matlab.ui.control.Label
        NewRoundButton                matlab.ui.control.Button
        NewGameButton                 matlab.ui.control.Button
        DealerStandingUI              matlab.ui.control.NumericEditField
        DealerStandingEditFieldLabel  matlab.ui.control.Label
        PlayerStandingUI              matlab.ui.control.NumericEditField
        PlayerStandingEditFieldLabel  matlab.ui.control.Label
        DealerTotalUI                 matlab.ui.control.NumericEditField
        DealerTotalEditFieldLabel     matlab.ui.control.Label
        PlayerTotalUI                 matlab.ui.control.NumericEditField
        PlayerTotalEditFieldLabel     matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        DiceValueButtonGroup          matlab.ui.container.ButtonGroup
        BottomDiceButton              matlab.ui.control.RadioButton
        TopDiceButton                 matlab.ui.control.RadioButton
        SumofDiceButton               matlab.ui.control.RadioButton
        StartButton                   matlab.ui.control.Button
        RoleButtonGroup               matlab.ui.container.ButtonGroup
        DealerButton                  matlab.ui.control.RadioButton
        PlayerButton                  matlab.ui.control.RadioButton
        StandButton                   matlab.ui.control.Button
        HitButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        % ThingSpeak Channel ID and Keys
        channelID = 2976444; % Replace with your ThingSpeak Channel ID
        writeKey = '7D4W0J88D5Z76I9N'; % Replace with your Write Key
        readKey = 'GX2EPQWYTSLNY04C'; % Replace with your Read Key
        
        myPlayerNum % 1 for player, 2 for dealer
        
        % Game state variables
        dice1 % Roll for dice 1
        dice2 % Roll for dice 2
        
        % ThingSpeak Fields
        playerStanding_TS 
        dealerStanding_TS 
        playerGameScore_TS 
        dealerGameScore_TS 
        turnAndAction_TS 
        gameStatus_TS 
        playerStoodAndDealerStood_TS 
        diceValues_TS 
        
        % Local game state variables
        whoseTurn 
        action % hit (1) or stand (0)
        gameEnded 
        overallGameEnded 
        playerStood 
        dealerStood 
        
        % Delays for ThingSpeak operations
        writeDelay = 15; 
        readDelay = 0.5; 

        % Dice Image Handling
        dice1Images % Cell array for dice 1 image handles
        dice2Images % Cell array for dice 2 image handles
    end
    
    methods (Access = private)
        
        function [] = ClearThingSpeakChannel(app)
            % Clears fields at start of new game
            try
                pause(app.writeDelay) % Ensure enough time if writes are frequent
                thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', zeros(1,8), 'WriteKey', app.writeKey);
                fprintf('ThingSpeak channel cleared.\n');
            catch ME
                fprintf('Error clearing ThingSpeak channel: %s\n', ME.message);
                app.MessageLabel.Text = 'Error: Could not clear channel.';
            end
        end        
        
        function SendDataToOtherPlayer(app)
            % Encode turn and action
            app.turnAndAction_TS = (app.whoseTurn * 10) + app.action;
            
            % Encode game status
            app.gameStatus_TS = (double(app.gameEnded) * 1) + (double(app.overallGameEnded) * 2);

            % Encode player stood status
            playerStoodVal = double(app.playerStood); 
            dealerStoodVal = double(app.dealerStood); 
            app.playerStoodAndDealerStood_TS = (playerStoodVal * 1) + (dealerStoodVal * 2);
            
            % Encode dice values (ensure they are numbers)
            if isempty(app.dice1) || isnan(app.dice1), app.dice1 = 0; end
            if isempty(app.dice2) || isnan(app.dice2), app.dice2 = 0; end
            app.diceValues_TS = (10 * app.dice1) + app.dice2;

            try
                pause(app.writeDelay); 
                thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', ...
                    [app.playerStanding_TS, app.dealerStanding_TS, app.playerGameScore_TS, ...
                    app.dealerGameScore_TS, app.turnAndAction_TS, app.gameStatus_TS, ...
                    app.playerStoodAndDealerStood_TS, app.diceValues_TS], 'WriteKey', app.writeKey);
                fprintf('Data sent to ThingSpeak.\n');
            catch ME
                fprintf('Error sending data to ThingSpeak: %s\n', ME.message);
                app.MessageLabel.Text = 'Error: Could not send data.';
            end
        end

        function WaitForOtherPlayer(app)
            app.MessageLabel.Text = 'Waiting for the other player...';
            app.setControlsEnabled(false); 
            drawnow;

            while true 
                try
                    dataTable = thingSpeakRead(app.channelID, 'Fields', 1:8, 'ReadKey', app.readKey, 'OutputFormat', 'table', 'NumPoints', 1);
                catch ME
                    fprintf('Error reading from ThingSpeak: %s\n', ME.message);
                    app.MessageLabel.Text = 'Error: Reading data failed. Retrying...';
                    pause(app.readDelay * 2); % Longer pause on error
                    continue;
                end
                
                if isempty(dataTable) || height(dataTable) < 1
                    pause(app.readDelay);
                    continue;
                end
                
                % Update local app properties from the latest ThingSpeak entry
                app.playerStanding_TS = dataTable.Variables(1); % Field1
                app.dealerStanding_TS = dataTable.Variables(2); % Field2
                app.playerGameScore_TS = dataTable.Variables(3); % Field3
                app.dealerGameScore_TS = dataTable.Variables(4); % Field4
                app.turnAndAction_TS = dataTable.Variables(5); % Field5
                app.gameStatus_TS = dataTable.Variables(6); % Field6
                app.playerStoodAndDealerStood_TS = dataTable.Variables(7); % Field7
                app.diceValues_TS = dataTable.Variables(8); % Field8
                
                % Decode turn and action
                app.whoseTurn = floor(app.turnAndAction_TS / 10);
                app.action = mod(app.turnAndAction_TS, 10);
                
                % Decode game status
                app.overallGameEnded = (app.gameStatus_TS >= 2); 
                app.gameEnded = (mod(app.gameStatus_TS, 2) == 1 || app.overallGameEnded);
                
                % Decode player stood status
                app.playerStood = (mod(app.playerStoodAndDealerStood_TS, 2) == 1);
                app.dealerStood = (app.playerStoodAndDealerStood_TS >= 2);

                % Decode dice values
                app.dice1 = floor(app.diceValues_TS / 10);
                app.dice2 = mod(app.diceValues_TS, 10); % Corrected from app.diceValues_TS - 10 * app.dice1
                
                app.UpdateStatus(); 

                % Update dice display based on the decoded app.dice1 and app.dice2
                if app.dice1 > 0 || app.dice2 > 0 
                    app.Dice1Label.Text = sprintf('Dice 1: %d', app.dice1);
                    app.Dice2Label.Text = sprintf('Dice 2: %d', app.dice2);
                    app.updateDiceImages(app.dice1, app.dice2);
                else 
                    app.Dice1Label.Text = 'Dice 1: -';
                    app.Dice2Label.Text = 'Dice 2: -';
                    app.updateDiceImages(0,0); 
                end
                drawnow;
                
                if app.overallGameEnded
                    app.handleOverallGameEnd();
                    return;
                end
                
                if (app.playerStood && app.dealerStood) || (app.playerStanding_TS > 21) || (app.dealerStanding_TS > 21)
                    if ~app.gameEnded % Determine winner only if not already processed
                        app.gameEnded = true; 
                        app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
                    end
                    % determineRoundWinner will call setControlsEnabled and SendData
                    return; 
                end

                if app.myPlayerNum == app.whoseTurn && ~app.gameEnded
                    if app.action == 0 && ((app.myPlayerNum == 1 && app.dealerStood) || (app.myPlayerNum == 2 && app.playerStood))
                         app.MessageLabel.Text = 'Other player stood. It''s your turn! Hit or Stand.';
                    elseif app.action == 1 % Other player just hit, it's my turn to react or wait if they bust
                         app.MessageLabel.Text = 'Other player hit. It''s your turn! Hit or Stand.';
                    else
                         app.MessageLabel.Text = 'It''s your turn! Click Hit or Stand.';
                    end
                    app.setControlsEnabled(true); 
                    break; 
                end
                
                pause(app.readDelay);
            end
        end

        function InitialRetrieveData(app)
            app.MessageLabel.Text = 'Connecting to game...';
            app.setControlsEnabled(false);
            drawnow;
            pause(app.writeDelay + 1); % Initial pause before first read attempt

            while true 
                try
                    dataTable = thingSpeakRead(app.channelID, 'Fields', 1:8, 'ReadKey', app.readKey, 'OutputFormat', 'table', 'NumPoints', 1);
                catch ME
                    fprintf('Error reading initial data from ThingSpeak: %s\n', ME.message);
                    app.MessageLabel.Text = 'Error: Reading game data. Retrying...';
                    pause(app.readDelay * 2); 
                    continue;
                end
                
                if isempty(dataTable) || height(dataTable) < 1
                    app.MessageLabel.Text = 'Waiting for initial game data from Dealer...';
                    pause(app.readDelay);
                    continue;
                end
                
                app.playerStanding_TS = dataTable.Variables(1);
                app.dealerStanding_TS = dataTable.Variables(2);
                app.playerGameScore_TS = dataTable.Variables(3);
                app.dealerGameScore_TS = dataTable.Variables(4);
                app.turnAndAction_TS = dataTable.Variables(5);
                app.gameStatus_TS = dataTable.Variables(6);
                app.playerStoodAndDealerStood_TS = dataTable.Variables(7);
                app.diceValues_TS = dataTable.Variables(8);
                
                app.whoseTurn = floor(app.turnAndAction_TS / 10);
                app.action = mod(app.turnAndAction_TS, 10);
                app.overallGameEnded = (app.gameStatus_TS >= 2); 
                app.gameEnded = (mod(app.gameStatus_TS, 2) == 1 || app.overallGameEnded);
                app.playerStood = (mod(app.playerStoodAndDealerStood_TS, 2) == 1);
                app.dealerStood = (app.playerStoodAndDealerStood_TS >= 2);
                app.dice1 = floor(app.diceValues_TS / 10);
                app.dice2 = mod(app.diceValues_TS, 10);
                
                app.UpdateStatus();

                if app.dice1 > 0 || app.dice2 > 0
                    app.Dice1Label.Text = sprintf('Dice 1: %d', app.dice1);
                    app.Dice2Label.Text = sprintf('Dice 2: %d', app.dice2);
                    app.updateDiceImages(app.dice1, app.dice2);
                else
                    app.Dice1Label.Text = 'Dice 1: -';
                    app.Dice2Label.Text = 'Dice 2: -';
                    app.updateDiceImages(0,0);
                end
                drawnow;

                if app.overallGameEnded
                    app.handleOverallGameEnd();
                    return; % Exit if game already over
                end

                % Player waits until it's their turn (after dealer's initial roll and action)
                if app.myPlayerNum == 1 && app.whoseTurn == 1 && app.dealerStanding_TS > 0
                    app.MessageLabel.Text = 'Dealer has made their first move. It''s your turn!';
                    app.setControlsEnabled(true);
                    break; 
                elseif app.myPlayerNum == 1 && app.dealerStanding_TS == 0
                     app.MessageLabel.Text = 'Waiting for Dealer''s initial roll...';
                else % Other conditions or roles, this might need refinement based on exact startup sequence.
                    app.MessageLabel.Text = 'Waiting for game to fully initialize...';
                end
                
                pause(app.readDelay);
            end
        end
        
        function UpdateStatus(app)
            app.PlayerStandingUI.Value = app.playerStanding_TS;
            app.DealerStandingUI.Value = app.dealerStanding_TS;
            app.PlayerTotalUI.Value = app.playerGameScore_TS; 
            app.DealerTotalUI.Value = app.dealerGameScore_TS; 
        end

        function setControlsEnabled(app, enable)
            if enable
                enableStr = 'on';
            else
                enableStr = 'off';
            end

            app.HitButton.Enable = enableStr;
            app.StandButton.Enable = enableStr;
            app.SelectButton.Enable = 'off'; % Always off until Hit enables it
            app.SumofDiceButton.Enable = 'off';
            app.TopDiceButton.Enable = 'off';
            app.BottomDiceButton.Enable = 'off';
            
            % NewRound and NewGame buttons are handled by game/round end logic
            if app.gameEnded && ~app.overallGameEnded
                app.NewRoundButton.Enable = 'on';
                app.HitButton.Enable = 'off'; % Ensure hit/stand are off if round ended
                app.StandButton.Enable = 'off';
            else
                app.NewRoundButton.Enable = 'off';
            end

            if app.overallGameEnded
                app.NewGameButton.Enable = 'on';
                app.HitButton.Enable = 'off'; % Ensure hit/stand are off if game ended
                app.StandButton.Enable = 'off';
                app.NewRoundButton.Enable = 'off';
            else
                app.NewGameButton.Enable = 'off';
            end
        end
        
        function [roll1, roll2] = performDiceRoll(app)
            % This function now ONLY returns dice values.
            % UI updates (labels, images) are handled by the calling function.
            roll1 = randi(6);
            roll2 = randi(6);
            app.dice1 = roll1; % Store locally
            app.dice2 = roll2; % Store locally
            % app.diceValues_TS is updated before SendDataToOtherPlayer
        end

        function animateDiceRoll(app)
            app.MessageLabel.Text = 'Rolling...';
            
            % Temporarily disable key controls during animation
            originalHitState = app.HitButton.Enable;
            originalStandState = app.StandButton.Enable;
            originalSelectState = app.SelectButton.Enable;
            originalNewRoundState = app.NewRoundButton.Enable;
            originalNewGameState = app.NewGameButton.Enable;

            app.HitButton.Enable = 'off';
            app.StandButton.Enable = 'off';
            app.SelectButton.Enable = 'off';
            app.NewRoundButton.Enable = 'off';
            app.NewGameButton.Enable = 'off';
            drawnow; 

            animationDuration = 1.0; % seconds
            flashDelay = 0.07;    % seconds for flash interval
            numFlashes = floor(animationDuration / flashDelay);

            for k = 1:numFlashes
                % Hide all dice images
                for i = 1:6
                    app.dice1Images{i}.Visible = 'off';
                    app.dice2Images{i}.Visible = 'off';
                end

                % Show random dice images
                randIdx1 = randi(6);
                randIdx2 = randi(6);
                app.dice1Images{randIdx1}.Visible = 'on';
                app.dice2Images{randIdx2}.Visible = 'on';

                drawnow; 
                pause(flashDelay);
            end

            % Hide all dice images after animation before showing the final result
            for i = 1:6
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end
            drawnow; 

            % Restore control states if game is not over
            if ~app.gameEnded && ~app.overallGameEnded
                app.HitButton.Enable = originalHitState;
                app.StandButton.Enable = originalStandState;
                % SelectButton is handled by HitButtonPushed logic
            end
            % NewRound/NewGame buttons are handled by their respective end-game logic.
        end

        function updateDiceImages(app, roll1, roll2)
            % Hide all dice images first
            for i = 1:6
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end

            % Show the correct dice images
            if roll1 >= 1 && roll1 <= 6
                app.dice1Images{roll1}.Visible = 'on';
            end
            if roll2 >= 1 && roll2 <= 6
                app.dice2Images{roll2}.Visible = 'on';
            end
            drawnow; % Ensure UI update
        end

        function [newStanding] = applyDiceValue(app, currentStanding, r1, r2, selection)
            newStanding = currentStanding;

            switch selection
                case 'Sum of Dice'
                    newStanding = newStanding + (r1 + r2);
                case 'Top Dice'
                    newStanding = newStanding + r1;
                case 'Bottom Dice'
                    newStanding = newStanding + r2;
            end
        end

        function determineRoundWinner(app, pStanding, dStanding)
            winnerMessage = '';
            
            playerBusted = pStanding > 21;
            dealerBusted = dStanding > 21;

            if playerBusted && dealerBusted
                winnerMessage = 'Both busted! It''s a tie.';
            elseif playerBusted
                winnerMessage = 'Player busted! Dealer wins round.';
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
            elseif dealerBusted
                winnerMessage = 'Dealer busted! Player wins round.';
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
            elseif pStanding == dStanding
                winnerMessage = 'Round tie! No points.';
            elseif pStanding > dStanding
                winnerMessage = 'Player wins the round!';
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
            else
                winnerMessage = 'Dealer wins the round!';
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
            end
            
            app.RoundWinnerLabel.Text = winnerMessage;
            app.MessageLabel.Text = 'Round Over!';
            app.UpdateStatus(); 
            app.gameEnded = true; % Explicitly set gameEnded here

            if app.playerGameScore_TS >= 5 || app.dealerGameScore_TS >= 5
                app.overallGameEnded = true;
                app.handleOverallGameEnd(); % This will also send data
            else
                app.setControlsEnabled(false); % Disables hit/stand, enables NewRound
                app.SendDataToOtherPlayer(); % Send final round state
            end
        end

        function handleOverallGameEnd(app)
            app.overallGameEnded = true; % Ensure this is set
            app.gameEnded = true; % Round is also over
            app.setControlsEnabled(false); % Disables hit/stand/newround, enables NewGame
            
            app.RoundWinnerLabel.Text = ''; 
            if app.playerGameScore_TS >= 5
                app.MessageLabel.Text = 'Game Over! Player wins the game!';
            elseif app.dealerGameScore_TS >= 5
                app.MessageLabel.Text = 'Game Over! Dealer wins the game!';
            else
                app.MessageLabel.Text = 'Game Over!'; % Generic if somehow scores aren't >=5 but game ended
            end
            app.SendDataToOtherPlayer(); % Send final game state
        end

        function startNewRound(app)
            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.whoseTurn = 2; % Dealer starts new round
            app.action = 0; % Reset action
            app.dice1 = 0;
            app.dice2 = 0;
            app.RoundWinnerLabel.Text = '';
            app.MessageLabel.Text = 'Starting new round...';
            app.gameEnded = false;
            app.overallGameEnded = false; % Should not be true unless starting new game after overall end
            app.playerStood = false; 
            app.dealerStood = false; 
            
            app.updateDiceImages(0,0); % Clear dice images
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.UpdateStatus();
            app.setControlsEnabled(false); % Disable all controls initially for the round
            drawnow;

            % Announce new round state (scores cleared, dealer's turn)
            app.SendDataToOtherPlayer(); % Send this initial cleared state

            if app.myPlayerNum == 2 % I AM THE DEALER
                app.MessageLabel.Text = 'Your turn (Dealer)! Performing initial roll...';
                drawnow;

                app.animateDiceRoll(); 
                [roll1, roll2] = app.performDiceRoll();
                app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
                app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
                app.updateDiceImages(roll1, roll2); 

                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice');
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus(); 
                app.MessageLabel.Text = sprintf('Dealer rolled %d & %d (Sum: %d). Your turn (Dealer): Hit or Stand.', roll1, roll2, newStanding);
                app.setControlsEnabled(true); % Enable dealer's controls
                app.action = 0; % Initial roll is not a chosen "hit" yet
                app.SendDataToOtherPlayer(); % Send updated state after dealer's roll
            else % I AM THE PLAYER
                app.MessageLabel.Text = 'New round. Waiting for Dealer''s move...';
                app.WaitForOtherPlayer(); 
            end
        end

        function resetGame(app)
            app.playerGameScore_TS = 0;
            app.dealerGameScore_TS = 0;
            % startNewRound will be called effectively by StartButton logic
            app.overallGameEnded = false; % Reset overall game flag
            app.gameEnded = false; % Reset round flag
            
            app.MessageLabel.Text = 'Welcome! Select your role to start.';
            app.RoundWinnerLabel.Text = '';
            app.updateDiceImages(0,0);
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.UpdateStatus(); % Show zeroed scores
            
            set(app.RoleButtonGroup, 'Visible', 'on'); 
            set(app.StartButton, 'Visible', 'on'); 
            set(app.NewGameButton, 'Enable', 'off'); 
            set(app.NewRoundButton, 'Enable', 'off');
            app.setControlsEnabled(false); % Disable game controls
            
            app.ClearThingSpeakChannel(); % Clear channel for a fresh game
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        % Store dice image handles in cell arrays
            app.dice1Images = {app.dice1_1, app.dice1_2, app.dice1_3, app.dice1_4, app.dice1_5, app.dice1_6};
            app.dice2Images = {app.dice2_1, app.dice2_2, app.dice2_3, app.dice2_4, app.dice2_5, app.dice2_6};

            % Initially hide all dice images
            for i = 1:6
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end

            app.setControlsEnabled(false);
            set(app.NewRoundButton, 'Visible', 'on'); % Make them visible but disabled
            set(app.NewRoundButton, 'Enable', 'off');
            set(app.NewGameButton, 'Visible', 'on');
            set(app.NewGameButton, 'Enable', 'off'); 
            
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            app.MessageLabel.Text = 'Welcome! Select your role to start.';
            
            app.UpdateStatus(); % Initialize UI with zeros (already done by default)
            app.ClearThingSpeakChannel(); % Clear channel on startup
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.RoleButtonGroup.SelectedObject.Text, 'Player')
                app.myPlayerNum = 1;
            else 
                app.myPlayerNum = 2;
            end

            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.playerGameScore_TS = 0;
            app.dealerGameScore_TS = 0;
            app.whoseTurn = 2; % Dealer makes the first move of the game
            app.action = 0; 
            app.gameEnded = false;
            app.overallGameEnded = false;
            app.playerStood = false; 
            app.dealerStood = false; 
            
            set(app.RoleButtonGroup, 'Visible', 'off'); 
            set(app.StartButton, 'Visible', 'off'); 
            
            app.UpdateStatus(); 
            app.updateDiceImages(0,0); % Clear dice images
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            drawnow;

            % Send initial state (roles set, scores 0, dealer's turn for first action)
            app.SendDataToOtherPlayer();

            if app.myPlayerNum == 2 % I AM THE DEALER
                app.MessageLabel.Text = 'Your turn (Dealer)! Performing initial roll...';
                drawnow;
                
                app.animateDiceRoll(); 
                [roll1, roll2] = app.performDiceRoll();
                app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
                app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
                app.updateDiceImages(roll1, roll2); 

                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice');
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus(); 
                app.MessageLabel.Text = sprintf('Dealer rolled %d & %d (Sum: %d). Your turn (Dealer): Hit or Stand.', roll1, roll2, newStanding);
                app.setControlsEnabled(true); 
                app.action = 0; % This is the initial state, not a "hit" action yet
                app.SendDataToOtherPlayer(); % Send the result of the initial roll
            else % I AM THE PLAYER (myPlayerNum == 1)
                app.MessageLabel.Text = 'Waiting for Dealer''s initial roll and action...';
                app.setControlsEnabled(false); 
                app.InitialRetrieveData(); % Player waits for data from Dealer.
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end
            
            app.action = 1; % Set action to Hit

            app.animateDiceRoll(); 

            [roll1, roll2] = app.performDiceRoll();
            app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
            app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
            app.updateDiceImages(roll1, roll2); 
            
            set(app.SelectButton, 'Enable', 'on');
            set(app.HitButton, 'Enable', 'off'); 
            set(app.StandButton, 'Enable', 'off'); 
            
            currentStanding = 0;
            if app.myPlayerNum == 1 
                currentStanding = app.playerStanding_TS;
            else 
                currentStanding = app.dealerStanding_TS;
            end

            if currentStanding >= 16
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','on');
                set(app.BottomDiceButton,'Enable','on');
            else
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','off');
                set(app.BottomDiceButton,'Enable','off');
                app.DiceValueButtonGroup.SelectedObject = app.SumofDiceButton; 
            end
            app.MessageLabel.Text = sprintf('You rolled %d & %d. Choose value.', roll1, roll2);
            app.SendDataToOtherPlayer();
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end

            set(app.SelectButton,'Enable','off');
            set(app.SumofDiceButton,'Enable','off');
            set(app.TopDiceButton,'Enable','off');
            set(app.BottomDiceButton,'Enable','off');
            
            currentSelection = app.DiceValueButtonGroup.SelectedObject.Text;
            newStanding = 0;

            if app.myPlayerNum == 1 
                newStanding = app.applyDiceValue(app.playerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.playerStanding_TS = newStanding;
            else 
                newStanding = app.applyDiceValue(app.dealerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.dealerStanding_TS = newStanding;
            end
            
            app.UpdateStatus(); 

            busted = false;
            if (app.myPlayerNum == 1 && app.playerStanding_TS > 21) || (app.myPlayerNum == 2 && app.dealerStanding_TS > 21)
                busted = true;
            end

            if busted
                app.MessageLabel.Text = 'You busted! Round ending...';
                app.gameEnded = true; % Set gameEnded before calling determineRoundWinner
                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS); 
                % determineRoundWinner will handle SendData and control enabling
            else
                app.MessageLabel.Text = 'Score updated. Hit again or Stand.';
                app.setControlsEnabled(true); % Re-enable Hit/Stand
                app.SendDataToOtherPlayer(); % Send updated score and dice (already sent by hit)
            end
       
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end

            app.action = 0; % Set action to Stand
            
            if app.myPlayerNum == 1
                app.playerStood = true;
            else 
                app.dealerStood = true;
            end

            % Switch turns
            if app.whoseTurn == 1
                app.whoseTurn = 2;
            else
                app.whoseTurn = 1;
            end
            
            app.setControlsEnabled(false); % Current player's controls off
            app.MessageLabel.Text = 'You stood. Waiting for other player...';
            drawnow;
            
            app.SendDataToOtherPlayer(); 
            
            if (app.playerStood && app.dealerStood) || (app.playerStanding_TS > 21) || (app.dealerStanding_TS > 21)
                if ~app.gameEnded % Check to avoid double processing
                    app.gameEnded = true;
                    app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
                end
            else
                app.WaitForOtherPlayer(); 
            end
        end

        % Button pushed function: NewRoundButton
        function NewRoundButtonPushed(app, event)
            app.startNewRound();
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.resetGame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.RoleButtonGroup = uibuttongroup(app.UIFigure);
            app.RoleButtonGroup.Title = 'Role';
            app.PlayerButton = uiradiobutton(app.RoleButtonGroup);
            app.PlayerButton.Text = 'Player';
            app.PlayerButton.Value = true;
            app.DealerButton = uiradiobutton(app.RoleButtonGroup);
            app.DealerButton.Text = 'Dealer';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DiceValueButtonGroup = uibuttongroup(app.UIFigure);
            app.DiceValueButtonGroup.Title = 'Dice Value';
            app.SumofDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.SumofDiceButton.Text = 'Sum of Dice';
            app.SumofDiceButton.Value = true;
            app.TopDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.TopDiceButton.Text = 'Top Dice';
            app.BottomDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.BottomDiceButton.Text = 'Bottom Dice';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PlayerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerTotalEditFieldLabel.Text = 'Player Total';
            app.PlayerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerTotalUI.AllowEmpty = 'on';
            app.DealerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.DealerTotalEditFieldLabel.Text = 'Dealer Total';
            app.DealerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerTotalUI.AllowEmpty = 'on';
            app.PlayerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerStandingEditFieldLabel.Text = 'Player Standing';
            app.PlayerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerStandingUI.AllowEmpty = 'on';
            app.DealerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.DealerStandingEditFieldLabel.Text = 'Dealer Standing';
            app.DealerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerStandingUI.AllowEmpty = 'on';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.NewRoundButton = uibutton(app.UIFigure, 'push');
            app.NewRoundButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoundButtonPushed, true);
            app.NewRoundButton.Text = 'New Round';
            app.Dice1Label = uilabel(app.UIFigure);
            app.Dice1Label.Text = 'Dice1';
            app.Dice2Label = uilabel(app.UIFigure);
            app.Dice2Label.Text = 'Dice2';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages';
            app.RoundWinnerLabel = uilabel(app.UIFigure);
            app.RoundWinnerLabel.Text = 'Round Winner';
            app.dice1_1 = uiimage(app.UIFigure);
            app.dice1_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice1_2 = uiimage(app.UIFigure);
            app.dice1_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice1_3 = uiimage(app.UIFigure);
            app.dice1_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice1_4 = uiimage(app.UIFigure);
            app.dice1_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice1_5 = uiimage(app.UIFigure);
            app.dice1_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice1_6 = uiimage(app.UIFigure);
            app.dice1_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            app.dice2_1 = uiimage(app.UIFigure);
            app.dice2_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice2_2 = uiimage(app.UIFigure);
            app.dice2_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice2_3 = uiimage(app.UIFigure);
            app.dice2_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice2_4 = uiimage(app.UIFigure);
            app.dice2_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice2_5 = uiimage(app.UIFigure);
            app.dice2_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice2_6 = uiimage(app.UIFigure);
            app.dice2_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end