classdef testV8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        dice2_6                       matlab.ui.control.Image
        dice2_5                       matlab.ui.control.Image
        dice2_4                       matlab.ui.control.Image
        dice2_3                       matlab.ui.control.Image
        dice2_2                       matlab.ui.control.Image
        dice2_1                       matlab.ui.control.Image
        dice1_6                       matlab.ui.control.Image
        dice1_5                       matlab.ui.control.Image
        dice1_4                       matlab.ui.control.Image
        dice1_3                       matlab.ui.control.Image
        dice1_2                       matlab.ui.control.Image
        dice1_1                       matlab.ui.control.Image
        RoundWinnerLabel              matlab.ui.control.Label
        MessageLabel                  matlab.ui.control.Label
        Dice2Label                    matlab.ui.control.Label
        Dice1Label                    matlab.ui.control.Label
        NewRoundButton                matlab.ui.control.Button
        NewGameButton                 matlab.ui.control.Button
        DealerStandingUI              matlab.ui.control.NumericEditField
        DealerStandingEditFieldLabel  matlab.ui.control.Label
        PlayerStandingUI              matlab.ui.control.NumericEditField
        PlayerStandingEditFieldLabel  matlab.ui.control.Label
        DealerTotalUI                 matlab.ui.control.NumericEditField
        DealerTotalEditFieldLabel     matlab.ui.control.Label
        PlayerTotalUI                 matlab.ui.control.NumericEditField
        PlayerTotalEditFieldLabel     matlab.ui.control.Label
        SelectButton                  matlab.ui.control.Button
        DiceValueButtonGroup          matlab.ui.container.ButtonGroup
        BottomDiceButton              matlab.ui.control.RadioButton
        TopDiceButton                 matlab.ui.control.RadioButton
        SumofDiceButton               matlab.ui.control.RadioButton
        StartButton                   matlab.ui.control.Button
        RoleButtonGroup               matlab.ui.container.ButtonGroup
        DealerButton                  matlab.ui.control.RadioButton
        PlayerButton                  matlab.ui.control.RadioButton
        StandButton                   matlab.ui.control.Button
        HitButton                     matlab.ui.control.Button
    end

    
   properties (Access = public)
        % ThingSpeak Channel ID and Keys
        channelID = 2976444; % Replace with your ThingSpeak Channel ID
        writeKey = '7D4W0J88D5Z76I9N'; % Replace with your Write Key
        readKey = 'GX2EPQWYTSLNY04C'; % Replace with your Read Key
        
        myPlayerNum % 1 for player, 2 for dealer
        
        % Game state variables
        dice1 % Roll for dice 1
        dice2 % Roll for dice 2
        
        % ThingSpeak Fields (ensure these are initialized in startup or when game starts)
        playerStanding_TS = 0;
        dealerStanding_TS = 0;
        playerGameScore_TS = 0;
        dealerGameScore_TS = 0;
        turnAndAction_TS = 0; % Stores whoseTurn (tens digit) and action (units digit)
        gameStatus_TS = 0;    % Encodes gameEnded and overallGameEnded
        playerStoodAndDealerStood_TS = 0; % Encodes playerStood and dealerStood
        diceValues_TS = 0;    % Encodes dice1 and dice2
        
        % Local game state variables
        whoseTurn = 0; % 1 for Player, 2 for Dealer. Initialized by Role selection.
        action = 0; % 0 for no specific action (e.g. stand, initial), 1 for hit
        gameEnded = false; % True if current round is over
        overallGameEnded = false; % True if game (first to 5 points) is over
        playerStood = false;
        dealerStood = false;
        
        % Delays for ThingSpeak operations
        writeDelay = 15; % Minimum delay for free ThingSpeak accounts
        readDelay = 1;   % How often to poll ThingSpeak when waiting

        % Dice Image Handling
        dice1Images % Cell array for dice 1 image handles
        dice2Images % Cell array for dice 2 image handles

        % Audio Player Objects
        audioClickPlayer
        audioDiceRollPlayer
        audioWaitingPlayer
        audioWinRoundPlayer
        audioWinGamePlayer
        audioLoseRoundPlayer % Optional: for losing a round
        audioPath % Path to audio files subfolder
    end
    
    methods (Access = private)

        function playSoundEffect(app, playerObject)
            if ~isempty(playerObject) && isvalid(playerObject)
                try
                    stop(playerObject); 
                    play(playerObject);
                catch ME
                    fprintf('Error playing sound: %s\n', ME.message);
                end
            else
                % fprintf('Warning: Audio player object is empty or invalid for playing.\n');
            end
        end
        
        function [] = ClearThingSpeakChannel(app)
            app.MessageLabel.Text = 'Clearing game channel... Please wait.';
            drawnow;
            app.playSoundEffect(app.audioWaitingPlayer);
            try
                pause(app.writeDelay); 
                thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', zeros(1,8), 'WriteKey', app.writeKey);
                fprintf('ThingSpeak channel cleared.\n');
                app.MessageLabel.Text = 'Channel cleared. Select role to start.';
            catch ME
                fprintf('Error clearing ThingSpeak channel: %s\n', ME.message);
                app.MessageLabel.Text = 'Error: Could not clear channel. Try restarting.';
            end
            drawnow;
        end
        
        function SendDataToOtherPlayer(app)
            % This function now assumes controls are ALREADY disabled by the caller
            % and the "Sending data..." message is ALREADY set by the caller.
            
            app.turnAndAction_TS = (app.whoseTurn * 10) + app.action;
            app.gameStatus_TS = (double(app.gameEnded) * 1) + (double(app.overallGameEnded) * 2);
            app.playerStoodAndDealerStood_TS = (double(app.playerStood) * 1) + (double(app.dealerStood) * 2);
            
            if isempty(app.dice1) || isnan(app.dice1), app.dice1 = 0; end
            if isempty(app.dice2) || isnan(app.dice2), app.dice2 = 0; end
            app.diceValues_TS = (10 * app.dice1) + app.dice2;

            dataToSend = [app.playerStanding_TS, app.dealerStanding_TS, app.playerGameScore_TS, ...
                          app.dealerGameScore_TS, app.turnAndAction_TS, app.gameStatus_TS, ...
                          app.playerStoodAndDealerStood_TS, app.diceValues_TS];
            try
                app.playSoundEffect(app.audioWaitingPlayer);
                pause(app.writeDelay); 
                thingSpeakWrite(app.channelID, 'Fields', 1:8, 'Values', dataToSend, 'WriteKey', app.writeKey);
                fprintf('Data sent to ThingSpeak: %s\n', mat2str(dataToSend));
            catch ME
                fprintf('Error sending data to ThingSpeak: %s\n', ME.message);
                app.MessageLabel.Text = 'Error: Could not send data. Check connection.';
                % Consider how to handle this - maybe allow retry or reset game
            end
        end
        
        function WaitForOtherPlayer(app)
            app.MessageLabel.Text = 'Waiting for other player''s move...';
            app.setControlsEnabled(false); % Ensure controls are off while waiting
            drawnow;

            while true 
                pause(app.readDelay); % Poll ThingSpeak at readDelay intervals
                try
                    dataTable = thingSpeakRead(app.channelID, 'Fields', 1:8, 'ReadKey', app.readKey, 'OutputFormat', 'table', 'NumPoints', 1);
                catch ME
                    fprintf('Error reading from ThingSpeak: %s\n', ME.message);
                    app.MessageLabel.Text = 'Error: Reading data failed. Retrying...';
                    drawnow;
                    pause(app.readDelay * 2); 
                    continue;
                end
                
                if isempty(dataTable) || height(dataTable) < 1
                    % fprintf('No new data from ThingSpeak. Waiting...\n');
                    continue; % No new data, keep waiting
                end
                
                % --- Update local app properties from the latest ThingSpeak entry ---
                % Adjusted column indices by +1 because thingSpeakRead with 'OutputFormat', 'table'
                % includes a 'Timestamps' column as the first column.
                new_playerStanding_TS = dataTable{1, 2}; % Corresponds to ThingSpeak Field1
                new_dealerStanding_TS = dataTable{1, 3}; % Corresponds to ThingSpeak Field2
                new_playerGameScore_TS = dataTable{1, 4}; % Corresponds to ThingSpeak Field3
                new_dealerGameScore_TS = dataTable{1, 5}; % Corresponds to ThingSpeak Field4
                new_turnAndAction_TS = dataTable{1, 6}; % Corresponds to ThingSpeak Field5
                new_gameStatus_TS = dataTable{1, 7};    % Corresponds to ThingSpeak Field6
                new_playerStoodAndDealerStood_TS = dataTable{1, 8}; % Corresponds to ThingSpeak Field7
                new_diceValues_TS = dataTable{1, 9};    % Corresponds to ThingSpeak Field8

                % Check if this is genuinely new data or the same data we just wrote (especially if readKey = writeKey)
                % A more robust way would be a timestamp or a sequence number if ThingSpeak supported it easily.
                isNewData = ~(app.playerStanding_TS == new_playerStanding_TS && ...
                              app.dealerStanding_TS == new_dealerStanding_TS && ...
                              app.turnAndAction_TS == new_turnAndAction_TS && ...
                              app.gameStatus_TS == new_gameStatus_TS && ...
                              app.playerStoodAndDealerStood_TS == new_playerStoodAndDealerStood_TS && ...
                              app.diceValues_TS == new_diceValues_TS);

                if ~isNewData && app.whoseTurn ~= app.myPlayerNum % If it's not my turn, I expect new data
                   % fprintf('Stale data received, and it is not my turn. Continuing to wait.\n');
                   % continue; % Potentially skip if data hasn't changed and it's not my turn
                end

                app.playerStanding_TS = new_playerStanding_TS;
                app.dealerStanding_TS = new_dealerStanding_TS;
                app.playerGameScore_TS = new_playerGameScore_TS;
                app.dealerGameScore_TS = new_dealerGameScore_TS;
                app.turnAndAction_TS = new_turnAndAction_TS;
                app.gameStatus_TS = new_gameStatus_TS;
                app.playerStoodAndDealerStood_TS = new_playerStoodAndDealerStood_TS;
                app.diceValues_TS = new_diceValues_TS;
                
                % Decode received state
                app.whoseTurn = floor(app.turnAndAction_TS / 10);
                app.action = mod(app.turnAndAction_TS, 10);
                app.overallGameEnded = (app.gameStatus_TS >= 2); 
                app.gameEnded = (mod(app.gameStatus_TS, 2) == 1 || app.overallGameEnded);
                app.playerStood = (mod(app.playerStoodAndDealerStood_TS, 2) == 1);
                app.dealerStood = (app.playerStoodAndDealerStood_TS >= 2);
                app.dice1 = floor(app.diceValues_TS / 10);
                app.dice2 = mod(app.diceValues_TS, 10);
                
                fprintf('Data received: PlayerStand=%d, DealerStand=%d, PScore=%d, DScore=%d, TurnAct=%d, GameStat=%d, StoodStat=%d, DiceVal=%d\n', ...
                        app.playerStanding_TS, app.dealerStanding_TS, app.playerGameScore_TS, app.dealerGameScore_TS, ...
                        app.turnAndAction_TS, app.gameStatus_TS, app.playerStoodAndDealerStood_TS, app.diceValues_TS);
                fprintf('Decoded: WhoseTurn=%d, Action=%d, GameEnded=%d, OverallGameEnded=%d, PlayerStood=%d, DealerStood=%d\n', ...
                        app.whoseTurn, app.action, app.gameEnded, app.overallGameEnded, app.playerStood, app.dealerStood);

                app.UpdateStatus(); % Update UI scores

                if app.dice1 > 0 || app.dice2 > 0 
                    app.Dice1Label.Text = sprintf('Dice 1: %d', app.dice1);
                    app.Dice2Label.Text = sprintf('Dice 2: %d', app.dice2);
                    app.updateDiceImages(app.dice1, app.dice2);
                else 
                    app.Dice1Label.Text = 'Dice 1: -';
                    app.Dice2Label.Text = 'Dice 2: -';
                    app.updateDiceImages(0,0); 
                end
                drawnow;
                
                if app.overallGameEnded
                    app.handleOverallGameEnd(); % This will set messages and button states
                    return; % Exit waiting loop
                end
                
                % If round ended based on received state (e.g. other player busted or stood making both stood)
                if app.gameEnded 
                    % The determineRoundWinner logic should have been called by the *other* player
                    % This player just needs to update UI for NewRound/NewGame button.
                    app.MessageLabel.Text = 'Round ended. Click New Round or New Game.';
                    app.setControlsEnabled(false); % This will enable NewRound/NewGame based on gameEnded/overallGameEnded
                    return; % Exit waiting loop
                end

                % It's my turn now
                if app.myPlayerNum == app.whoseTurn && ~app.gameEnded
                    if app.myPlayerNum == 2 % DEALER'S TURN LOGIC
                        % Dealer must hit until 18 or higher
                        while app.dealerStanding_TS < 18 && ~app.gameEnded && ~app.playerStood % Continue hitting if below 18 and game not ended
                            app.MessageLabel.Text = sprintf('Dealer hits (current: %d)...', app.dealerStanding_TS);
                            drawnow;
                            app.animateDiceRoll();
                            [roll1, roll2] = app.performDiceRoll();
                            app.Dice1Label.Text = sprintf('Dice 1: %d', roll1);
                            app.Dice2Label.Text = sprintf('Dice 2: %d', roll2);
                            app.updateDiceImages(roll1, roll2);
                            
                            % Dealer always takes sum for automatic hits
                            app.dealerStanding_TS = app.applyDiceValue(app.dealerStanding_TS, roll1, roll2, 'Sum of Dice');
                            app.UpdateStatus();
                            
                            % Check if dealer busted
                            if app.dealerStanding_TS > 21
                                app.gameEnded = true;
                                app.MessageLabel.Text = 'Dealer busted! Sending state...';
                                drawnow;
                                app.SendDataToOtherPlayer(); % Send busted state
                                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
                                return; % Exit waiting loop as round is over
                            end
                            
                            app.action = 1; % Indicate a hit action
                            app.SendDataToOtherPlayer(); % Send updated standing after each hit
                            pause(app.writeDelay); % Pause after sending to allow ThingSpeak to update
                        end
                        
                        % After loop, if dealer hasn't busted, they stand
                        if ~app.gameEnded
                            app.dealerStood = true;
                            app.action = 0; % Action is "stand"
                            app.whoseTurn = 1; % Pass turn back to player (even if player stood)
                            app.MessageLabel.Text = sprintf('Dealer stands at %d. Sending state...', app.dealerStanding_TS);
                            drawnow;
                            app.SendDataToOtherPlayer(); % Send stood state and new turn
                            
                            % Check if both stood (or one stood and other already busted)
                            if app.playerStood && app.dealerStood
                                app.MessageLabel.Text = 'Both players stood. Determining winner...';
                                drawnow;
                                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
                            else
                                app.MessageLabel.Text = 'Dealer stood. Waiting for Player...';
                                drawnow;
                                % Dealer waits for player's final move if player hasn't stood
                                app.WaitForOtherPlayer(); % Recursive call to continue waiting
                            end
                        end
                        return; % Exit waiting loop as dealer's turn is handled
                    else % PLAYER'S TURN LOGIC
                        app.MessageLabel.Text = 'It''s your turn! Click Hit or Stand.';
                        if app.action == 1 % If other player just hit (and didn't bust), their dice are shown
                            app.MessageLabel.Text = sprintf('Opponent rolled %d & %d. Your turn!', app.dice1, app.dice2);
                        elseif (app.myPlayerNum == 1 && app.dealerStood)
                            app.MessageLabel.Text = 'Dealer stood. Your turn (Player)!';
                        end
                        app.setControlsEnabled(true); 
                        drawnow;
                        break; % Exit loop, it's my turn
                    end
                else
                    app.MessageLabel.Text = 'Waiting for other player''s move...'; % Still not my turn or game ended
                    drawnow;
                end
            end
        end

        function InitialRetrieveData(app)
            % Simplified version of WaitForOtherPlayer for the very first data after Start
            app.MessageLabel.Text = 'Connecting to game... Waiting for Dealer''s first move.';
            app.setControlsEnabled(false);
            drawnow;
            % No initial pause here, WaitForOtherPlayer handles polling
            app.WaitForOtherPlayer(); % Re-use the main waiting logic
        end
        
        function UpdateStatus(app)
            app.PlayerStandingUI.Value = app.playerStanding_TS;
            app.DealerStandingUI.Value = app.dealerStanding_TS;
            app.PlayerTotalUI.Value = app.playerGameScore_TS; 
            app.DealerTotalUI.Value = app.dealerGameScore_TS; 
        end

        function setControlsEnabled(app, enable)
            % This function controls the enabling/disabling of game controls
            % based on the current game state and player's role.
            
            % Default enable string
            enableStr = 'off'; 
            if enable && app.myPlayerNum == app.whoseTurn && ~app.gameEnded && ~app.overallGameEnded
                enableStr = 'on';
            end

            % Enable/disable Hit button
            app.HitButton.Enable = enableStr;

            % Enable/disable Stand button
            % Player cannot stand on first turn (standing_TS is 0)
            if app.myPlayerNum == 1 && app.playerStanding_TS == 0 
                app.StandButton.Enable = 'off';
            else
                app.StandButton.Enable = enableStr;
            end
            
            % SelectButton and DiceValueButtonGroup are enabled contextually by HitButtonPushed
            if ~strcmp(enableStr, 'on') % If disabling main controls, ensure these are also off
                app.SelectButton.Enable = 'off'; 
                app.SumofDiceButton.Enable = 'off';
                app.TopDiceButton.Enable = 'off';
                app.BottomDiceButton.Enable = 'off';
            end
            
            % NewRound button: Only enabled for Dealer when a round ends and game is not overall ended
            if app.gameEnded && ~app.overallGameEnded && app.myPlayerNum == 2
                app.NewRoundButton.Enable = 'on';
            else
                app.NewRoundButton.Enable = 'off';
            end

            % NewGame button: Only enabled for Dealer when overall game ends
            if app.overallGameEnded && app.myPlayerNum == 2
                app.NewGameButton.Enable = 'on';
            else
                app.NewGameButton.Enable = 'off'; 
            end
        end

        function [roll1, roll2] = performDiceRoll(app)
            roll1 = randi(6);
            roll2 = randi(6);
            app.dice1 = roll1; 
            app.dice2 = roll2; 
        end

        function animateDiceRoll(app)
            app.playSoundEffect(app.audioDiceRollPlayer); 
            app.MessageLabel.Text = 'Rolling...';
            
            % Controls should already be disabled by the calling function before animation
            drawnow; 

            animationDuration = 0.7; % Shortened for responsiveness
            flashDelay = 0.06;    
            numFlashes = floor(animationDuration / flashDelay);

            for k = 1:numFlashes
                for i = 1:6
                    app.dice1Images{i}.Visible = 'off';
                    app.dice2Images{i}.Visible = 'off';
                end
                randIdx1 = randi(6);
                randIdx2 = randi(6);
                app.dice1Images{randIdx1}.Visible = 'on';
                app.dice2Images{randIdx2}.Visible = 'on';
                drawnow; 
                pause(flashDelay);
            end

            for i = 1:6 % Ensure dice are hidden before showing final result
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end
            drawnow; 
        end

        function updateDiceImages(app, roll1, roll2)
            for i = 1:6
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end
            if roll1 >= 1 && roll1 <= 6
                app.dice1Images{roll1}.Visible = 'on';
            end
            if roll2 >= 1 && roll2 <= 6
                app.dice2Images{roll2}.Visible = 'on';
            end
            drawnow; 
        end

        function [newStanding] = applyDiceValue(app, currentStanding, r1, r2, selection)
            newStanding = currentStanding;
            switch selection
                case 'Sum of Dice'
                    newStanding = newStanding + (r1 + r2);
                case 'Top Dice' % Assuming dice1 is Top
                    newStanding = newStanding + r1;
                case 'Bottom Dice' % Assuming dice2 is Bottom
                    newStanding = newStanding + r2;
            end
        end

        function determineRoundWinner(app, pStanding, dStanding)
            % This function is called when a round definitively ends (bust, or both stood)
            % The player whose action triggered this (bust, or the second stand) is responsible for calling this.
            
            app.gameEnded = true; % Mark round as ended
            winnerMessage = '';
            playerWonRound = false; 
            dealerWonRound = false;
            
            playerBusted = pStanding > 21;
            dealerBusted = dStanding > 21;

            if playerBusted && dealerBusted
                winnerMessage = 'Both busted! Round is a tie.';
            elseif pStanding == 21 && dStanding == 21 % FIX: Both hit 21 is a tie
                winnerMessage = 'Both hit 21! Round is a tie!';
                app.playSoundEffect(app.audioWinRoundPlayer); % Play win sound for both
            elseif playerBusted
                winnerMessage = sprintf('Player busted with %d! Dealer wins round.', pStanding);
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
                dealerWonRound = true;
            elseif dealerBusted
                winnerMessage = sprintf('Dealer busted with %d! Player wins round.', dStanding);
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
                playerWonRound = true;
            elseif pStanding == dStanding
                winnerMessage = sprintf('Both stood at %d. Round is a tie!', pStanding);
            elseif pStanding > dStanding
                winnerMessage = sprintf('Player wins round with %d vs %d!', pStanding, dStanding);
                app.playerGameScore_TS = app.playerGameScore_TS + 1;
                playerWonRound = true;
            else % dStanding > pStanding
                winnerMessage = sprintf('Dealer wins round with %d vs %d!', dStanding, pStanding);
                app.dealerGameScore_TS = app.dealerGameScore_TS + 1;
                dealerWonRound = true;
            end
            
            app.RoundWinnerLabel.Text = winnerMessage;
            app.MessageLabel.Text = 'Round Over! Sending final round state...';
            app.UpdateStatus(); 
            drawnow;

            if (app.myPlayerNum == 1 && playerWonRound) || (app.myPlayerNum == 2 && dealerWonRound)
                app.playSoundEffect(app.audioWinRoundPlayer);
            elseif (app.myPlayerNum == 1 && dealerWonRound) || (app.myPlayerNum == 2 && playerWonRound)
                app.playSoundEffect(app.audioLoseRoundPlayer); % Play lose sound if implemented
            end

            % Check for overall game win
            if app.playerGameScore_TS >= 5 || app.dealerGameScore_TS >= 5
                app.overallGameEnded = true;
                % SendData will be called by handleOverallGameEnd
            end
            
            % Disable active controls, SendData, then handleOverallGameEnd or enable NewRound
            app.setControlsEnabled(false); % Disables Hit/Stand, Select
            app.SendDataToOtherPlayer(); % Send the final state of the round

            if app.overallGameEnded
                app.handleOverallGameEnd(); % This will update messages and NewGameButton
            else
                app.MessageLabel.Text = [winnerMessage, ' Click New Round.'];
                app.NewRoundButton.Enable = 'on'; % Explicitly enable NewRound here
                drawnow;
            end
        end

        function handleOverallGameEnd(app)
            % This is called after round winner determination if game score reaches 5
            % Or if WaitForOtherPlayer receives an overallGameEnded state
            app.overallGameEnded = true; 
            app.gameEnded = true; % Round is also over
            
            finalMessage = '';
            playerWonGame = app.playerGameScore_TS >= 5;
            dealerWonGame = app.dealerGameScore_TS >= 5;

            if playerWonGame
                finalMessage = 'GAME OVER! Player wins the game!';
            elseif dealerWonGame
                finalMessage = 'GAME OVER! Dealer wins the game!';
            else
                finalMessage = 'GAME OVER!'; % Should ideally not happen if logic is correct
            end
            
            app.MessageLabel.Text = finalMessage;
            app.RoundWinnerLabel.Text = ''; % Clear round winner text
            drawnow;

            if (app.myPlayerNum == 1 && playerWonGame) || (app.myPlayerNum == 2 && dealerWonGame)
                app.playSoundEffect(app.audioWinGamePlayer);
            elseif (app.myPlayerNum == 1 && dealerWonGame) || (app.myPlayerNum == 2 && playerWonGame)
                 app.playSoundEffect(app.audioLoseRoundPlayer); % Or a specific game lose sound
            end
            
            % Ensure all game controls are off, and NewGame is on
            app.setControlsEnabled(false); % This will enable NewGameButton due to overallGameEnded
            app.NewGameButton.Enable = 'on'; % Explicitly
            app.NewRoundButton.Enable = 'off';% Explicitly
            
            % If this player is the one triggering game end, they already sent data in determineRoundWinner.
            % If they are RECEIVING game end, no need to send again.
            % So, SendDataToOtherPlayer() is typically not called directly from here,
            % as the state leading to overallGameEnded should have already been sent.
        end

        function startNewRound(app)
            app.playSoundEffect(app.audioClickPlayer);
            app.MessageLabel.Text = 'Starting new round... Please wait.';
            app.setControlsEnabled(false); % Disable all controls first
            drawnow;
            
            % Reset round-specific states locally
            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.action = 0; 
            app.dice1 = 0;
            app.dice2 = 0;
            app.gameEnded = false;
            app.playerStood = false; 
            app.dealerStood = false; 
            
            app.updateDiceImages(0,0); 
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            app.UpdateStatus(); % This updates the UI with the *current* TS values
            drawnow;

            % Dealer makes the first move in a new round
            if app.myPlayerNum == 2 % I AM THE DEALER
                app.MessageLabel.Text = 'Your turn (Dealer)! Performing initial roll for new round...';
                app.setControlsEnabled(false); % Keep controls off during this auto-action
                drawnow;

                % Clear ThingSpeak channel only by the dealer for new round
                app.ClearThingSpeakChannel(); % FIX: Dealer clears channel for new round
                
                app.animateDiceRoll(); 
                [roll1, roll2] = app.performDiceRoll();
                app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
                app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
                app.updateDiceImages(roll1, roll2); 

                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice'); % Dealer always uses sum for initial
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus(); 
                
                app.whoseTurn = 1; % After dealer's initial roll, it's Player's turn
                app.action = 0; % Initial roll state
                
                app.MessageLabel.Text = 'Sending initial round state... Please wait.';
                drawnow;
                app.SendDataToOtherPlayer(); 
                
                app.MessageLabel.Text = sprintf('Dealer initially rolled for %d. Waiting for Player.', app.dealerStanding_TS);
                drawnow;
                app.WaitForOtherPlayer(); % Dealer now waits for Player's first move
            else % I AM THE PLAYER
                app.MessageLabel.Text = 'New round. Waiting for Dealer''s initial move...';
                drawnow;
                app.whoseTurn = 2; % Indicate it's dealer's turn to make the first move
                app.action = 0;
                app.WaitForOtherPlayer(); % Player waits for dealer's roll
            end
        end

        function resetGame(app)
            app.playSoundEffect(app.audioClickPlayer);
            app.MessageLabel.Text = 'Resetting game... Please wait.';
            app.setControlsEnabled(false);
            drawnow;

            % Reset all game scores and states locally
            app.playerGameScore_TS = 0;
            app.dealerGameScore_TS = 0;
            app.playerStanding_TS = 0;
            app.dealerStanding_TS = 0;
            app.whoseTurn = 0; % Will be set by role selection
            app.action = 0;
            app.dice1 = 0;
            app.dice2 = 0;
            app.gameEnded = false; 
            app.overallGameEnded = false; 
            app.playerStood = false;
            app.dealerStood = false;
            
            app.UpdateStatus(); 
            app.updateDiceImages(0,0);
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            
            % Make role selection visible again
            set(app.RoleButtonGroup, 'Visible', 'on'); 
            set(app.StartButton, 'Visible', 'on'); 
            app.StartButton.Enable = 'on'; % Ensure start button is enabled
            
            % NewGame/NewRound buttons should be off until context allows
            set(app.NewGameButton, 'Enable', 'off'); 
            set(app.NewRoundButton, 'Enable', 'off');
            
            % FIX: Only Dealer clears the ThingSpeak channel for a full game reset
            if app.myPlayerNum == 2 
                app.ClearThingSpeakChannel(); % This has its own message and pause
                % After clearing, dealer sends the initial state for the new game
                app.SendDataToOtherPlayer(); 
                app.MessageLabel.Text = 'Game reset. Dealer, select role and click Start.';
            else % Player waits for dealer to clear and send initial state
                app.MessageLabel.Text = 'Game reset. Waiting for Dealer to start new game.';
                app.WaitForOtherPlayer(); % Player waits for dealer's initial setup
            end
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audioPath = fullfile(fileparts(mfilename('fullpath')), 'audioFiles');
            try
                [y, Fs] = audioread(fullfile(app.audioPath, 'click.wav')); 
                app.audioClickPlayer = audioplayer(y, Fs);
                [y, Fs] = audioread(fullfile(app.audioPath, 'dice_roll.wav')); 
                app.audioDiceRollPlayer = audioplayer(y, Fs);
                [y, Fs] = audioread(fullfile(app.audioPath, 'waiting_sound.wav')); 
                app.audioWaitingPlayer = audioplayer(y, Fs);
                [y, Fs] = audioread(fullfile(app.audioPath, 'win_game.wav')); % Corrected filename
                app.audioWinRoundPlayer = audioplayer(y, Fs);
                [y, Fs] = audioread(fullfile(app.audioPath, 'win_game.wav')); 
                app.audioWinGamePlayer = audioplayer(y, Fs);
                % Optional: Load a lose sound
                % [y, Fs] = audioread(fullfile(app.audioPath, 'lose_round.wav'));
                % app.audioLoseRoundPlayer = audioplayer(y, Fs);
            catch ME
                fprintf('Error loading audio files: %s\n', ME.message);
                app.MessageLabel.Text = 'Warning: Audio files missing or invalid.';
                app.audioClickPlayer = []; app.audioDiceRollPlayer = [];
                app.audioWaitingPlayer = []; app.audioWinRoundPlayer = [];
                app.audioWinGamePlayer = []; app.audioLoseRoundPlayer = [];
            end

            app.dice1Images = {app.dice1_1, app.dice1_2, app.dice1_3, app.dice1_4, app.dice1_5, app.dice1_6};
            app.dice2Images = {app.dice2_1, app.dice2_2, app.dice2_3, app.dice2_4, app.dice2_5, app.dice2_6};

            for i = 1:6
                app.dice1Images{i}.Visible = 'off';
                app.dice2Images{i}.Visible = 'off';
            end

            % Explicitly disable all game controls at startup
            app.HitButton.Enable = 'off';
            app.StandButton.Enable = 'off';
            app.SelectButton.Enable = 'off';
            app.SumofDiceButton.Enable = 'off';
            app.TopDiceButton.Enable = 'off';
            app.BottomDiceButton.Enable = 'off';
            app.DiceValueButtonGroup.Enable = 'off'; % Disable the whole group
            app.NewRoundButton.Enable = 'off';
            app.NewGameButton.Enable = 'off';

            % Enable only the initial role selection and start button
            set(app.RoleButtonGroup, 'Visible', 'on');
            set(app.RoleButtonGroup, 'Enable', 'on'); % Ensure the group itself is enabled
            set(app.StartButton, 'Visible', 'on');
            set(app.StartButton, 'Enable', 'on');
            
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            app.MessageLabel.Text = 'Welcome! Select your role and click Start.';
            
            app.UpdateStatus(); 
            app.ClearThingSpeakChannel(); % Clear channel on startup, includes a delay & message
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.playSoundEffect(app.audioClickPlayer);
            
            if strcmp(app.RoleButtonGroup.SelectedObject.Text, 'Player')
                app.myPlayerNum = 1;
            else 
                app.myPlayerNum = 2;
            end

            % Initial game state setup
            app.playerStanding_TS = 0; app.dealerStanding_TS = 0;
            app.playerGameScore_TS = 0; app.dealerGameScore_TS = 0;
            app.action = 0; 
            app.gameEnded = false; app.overallGameEnded = false;
            app.playerStood = false; app.dealerStood = false; 
            app.dice1=0; app.dice2=0;
            
            set(app.RoleButtonGroup, 'Visible', 'off'); 
            set(app.StartButton, 'Enable', 'off'); % Disable start button after pressed
            app.setControlsEnabled(false); % Ensure other game controls are off
            
            app.UpdateStatus(); 
            app.updateDiceImages(0,0); 
            app.Dice1Label.Text = 'Dice 1: -';
            app.Dice2Label.Text = 'Dice 2: -';
            app.RoundWinnerLabel.Text = '';
            drawnow;

            if app.myPlayerNum == 2 % I AM THE DEALER
                app.MessageLabel.Text = 'Your turn (Dealer)! Performing initial roll...';
                drawnow;
                
                app.animateDiceRoll(); 
                [roll1, roll2] = app.performDiceRoll();
                app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
                app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
                app.updateDiceImages(roll1, roll2); 

                [newStanding] = app.applyDiceValue(0, roll1, roll2, 'Sum of Dice'); % Dealer always uses sum for initial
                app.dealerStanding_TS = newStanding;
                app.UpdateStatus(); 
                
                app.whoseTurn = 1; % After dealer's initial roll, it's Player's turn
                app.action = 0; % Initial roll state
                
                app.MessageLabel.Text = 'Sending initial game state... Please wait.';
                drawnow;
                app.SendDataToOtherPlayer(); % This includes the 15s delay
                
                app.MessageLabel.Text = sprintf('Dealer initially rolled for %d. Waiting for Player.', app.dealerStanding_TS);
                drawnow;
                app.WaitForOtherPlayer(); % Dealer now waits for Player's first move
            else % I AM THE PLAYER (myPlayerNum == 1)
                app.whoseTurn = 2; % Player expects Dealer to make the first move
                app.action = 0;
                % Player doesn't send data here, waits for Dealer's setup.
                app.MessageLabel.Text = 'Waiting for Dealer''s initial setup...';
                drawnow;
                app.InitialRetrieveData(); % Player waits for Dealer's data
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.playSoundEffect(app.audioClickPlayer);
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end
            
            app.setControlsEnabled(false); % Disable Hit/Stand immediately
            app.MessageLabel.Text = 'Rolling...';
            drawnow;

            app.animateDiceRoll(); 
            [roll1, roll2] = app.performDiceRoll(); % dice1 and dice2 are updated here
            app.Dice1Label.Text = sprintf('Dice 1: %d', roll1); 
            app.Dice2Label.Text = sprintf('Dice 2: %d', roll2); 
            app.updateDiceImages(roll1, roll2); 
            
            % Enable selection UI
            app.SelectButton.Enable = 'on';
            app.DiceValueButtonGroup.Enable = 'on'; % FIX: Enable the button group itself
            currentStanding = 0;
            if app.myPlayerNum == 1 
                currentStanding = app.playerStanding_TS;
            else 
                currentStanding = app.dealerStanding_TS;
            end

            if currentStanding >= 16
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','on');
                set(app.BottomDiceButton,'Enable','on');
            else
                set(app.SumofDiceButton,'Enable','on');
                set(app.TopDiceButton,'Enable','off'); % Cannot select single if under 16
                set(app.BottomDiceButton,'Enable','off');% Cannot select single if under 16
                app.DiceValueButtonGroup.SelectedObject = app.SumofDiceButton; % Default to Sum
            end
            app.MessageLabel.Text = sprintf('You rolled %d & %d. Choose value to use.', roll1, roll2);
            
            % Send state indicating dice have been rolled, action is "hit" (1)
            app.action = 1; 
            % No SendDataToOtherPlayer here. Data is sent AFTER SelectButton is pushed.
            % The other player doesn't need to know the dice values until they are applied.
            % This simplifies state and reduces ThingSpeak writes.
            % The state sent by SelectButtonPushed will reflect the new standing.
            drawnow;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.playSoundEffect(app.audioClickPlayer);
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end

            app.setControlsEnabled(false); % Disable all controls during processing and send
            app.SelectButton.Enable = 'off'; % Specifically disable select button
            app.SumofDiceButton.Enable = 'off';
            app.TopDiceButton.Enable = 'off';
            app.BottomDiceButton.Enable = 'off';
            app.DiceValueButtonGroup.Enable = 'off'; % FIX: Disable the button group after selection
            drawnow;
            
            currentSelection = app.DiceValueButtonGroup.SelectedObject.Text;
            newStanding = 0;

            if app.myPlayerNum == 1 
                newStanding = app.applyDiceValue(app.playerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.playerStanding_TS = newStanding;
            else % Dealer
                newStanding = app.applyDiceValue(app.dealerStanding_TS, app.dice1, app.dice2, currentSelection);
                app.dealerStanding_TS = newStanding;
            end
            
            app.UpdateStatus(); 
            app.action = 1; % Action was a hit that led to this selection

            busted = (app.myPlayerNum == 1 && app.playerStanding_TS > 21) || ...
                     (app.myPlayerNum == 2 && app.dealerStanding_TS > 21);

            if busted
                app.MessageLabel.Text = 'You busted! Sending state...';
                drawnow;
                app.SendDataToOtherPlayer(); % Send busted state
                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS); % This will handle further messages
            elseif app.myPlayerNum == 1 && app.playerStanding_TS == 21 % FIX: Player hits 21, automatically stands, turn passes to dealer
                app.MessageLabel.Text = 'You hit 21! Waiting for Dealer to play.';
                app.playerStood = true; % Player automatically stands on 21
                app.whoseTurn = 2; % Pass turn to dealer
                app.action = 0; % Reset action
                drawnow;
                app.SendDataToOtherPlayer(); % Send updated state
                app.WaitForOtherPlayer(); % Player waits for dealer's turn
            else
                app.MessageLabel.Text = 'Score updated. Sending state...';
                drawnow;
                app.SendDataToOtherPlayer(); % Send updated score
                
                % After send, re-enable controls for the current player
                app.MessageLabel.Text = 'Your turn. Hit or Stand.';
                app.setControlsEnabled(true); % Re-enables Hit/Stand for current player
            end
            drawnow;
       
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            app.playSoundEffect(app.audioClickPlayer);
            if app.gameEnded || app.overallGameEnded || app.myPlayerNum ~= app.whoseTurn
                return; 
            end

            app.setControlsEnabled(false); % Disable controls
            app.MessageLabel.Text = 'You stood. Sending state... Please wait.';
            drawnow;
            
            app.action = 0; % Action is "stand"
            if app.myPlayerNum == 1
                app.playerStood = true;
            else 
                app.dealerStood = true;
            end

            % Switch turns BEFORE sending data
            if app.whoseTurn == 1
                app.whoseTurn = 2;
            else
                app.whoseTurn = 1;
            end
            
            app.SendDataToOtherPlayer(); % Send stood state and new turn
            
            % Check if both stood (or one stood and other already busted - handled by gameEnded)
            if (app.playerStood && app.dealerStood)
                app.MessageLabel.Text = 'Both players stood. Determining winner...';
                drawnow;
                app.determineRoundWinner(app.playerStanding_TS, app.dealerStanding_TS);
            else
                app.MessageLabel.Text = 'You stood. Waiting for other player...';
                drawnow;
                app.WaitForOtherPlayer(); 
            end
        end

        % Button pushed function: NewRoundButton
        function NewRoundButtonPushed(app, event)
            app.startNewRound();
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.resetGame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.RoleButtonGroup = uibuttongroup(app.UIFigure);
            app.RoleButtonGroup.Title = 'Role';
            app.PlayerButton = uiradiobutton(app.RoleButtonGroup);
            app.PlayerButton.Text = 'Player';
            app.PlayerButton.Value = true;
            app.DealerButton = uiradiobutton(app.RoleButtonGroup);
            app.DealerButton.Text = 'Dealer';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DiceValueButtonGroup = uibuttongroup(app.UIFigure);
            app.DiceValueButtonGroup.Title = 'Dice Value';
            app.SumofDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.SumofDiceButton.Text = 'Sum of Dice';
            app.SumofDiceButton.Value = true;
            app.TopDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.TopDiceButton.Text = 'Top Dice';
            app.BottomDiceButton = uiradiobutton(app.DiceValueButtonGroup);
            app.BottomDiceButton.Text = 'Bottom Dice';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PlayerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerTotalEditFieldLabel.Text = 'Player Total';
            app.PlayerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerTotalUI.AllowEmpty = 'on';
            app.DealerTotalEditFieldLabel = uilabel(app.UIFigure);
            app.DealerTotalEditFieldLabel.Text = 'Dealer Total';
            app.DealerTotalUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerTotalUI.AllowEmpty = 'on';
            app.PlayerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerStandingEditFieldLabel.Text = 'Player Standing';
            app.PlayerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.PlayerStandingUI.AllowEmpty = 'on';
            app.DealerStandingEditFieldLabel = uilabel(app.UIFigure);
            app.DealerStandingEditFieldLabel.Text = 'Dealer Standing';
            app.DealerStandingUI = uieditfield(app.UIFigure, 'numeric');
            app.DealerStandingUI.AllowEmpty = 'on';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.NewRoundButton = uibutton(app.UIFigure, 'push');
            app.NewRoundButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoundButtonPushed, true);
            app.NewRoundButton.Text = 'New Round';
            app.Dice1Label = uilabel(app.UIFigure);
            app.Dice1Label.Text = 'Dice1';
            app.Dice2Label = uilabel(app.UIFigure);
            app.Dice2Label.Text = 'Dice2';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages';
            app.RoundWinnerLabel = uilabel(app.UIFigure);
            app.RoundWinnerLabel.Text = 'Round Winner';
            app.dice1_1 = uiimage(app.UIFigure);
            app.dice1_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice1_2 = uiimage(app.UIFigure);
            app.dice1_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice1_3 = uiimage(app.UIFigure);
            app.dice1_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice1_4 = uiimage(app.UIFigure);
            app.dice1_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice1_5 = uiimage(app.UIFigure);
            app.dice1_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice1_6 = uiimage(app.UIFigure);
            app.dice1_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            app.dice2_1 = uiimage(app.UIFigure);
            app.dice2_1.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice1.png');
            app.dice2_2 = uiimage(app.UIFigure);
            app.dice2_2.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice2.png');
            app.dice2_3 = uiimage(app.UIFigure);
            app.dice2_3.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice3.png');
            app.dice2_4 = uiimage(app.UIFigure);
            app.dice2_4.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice4.png');
            app.dice2_5 = uiimage(app.UIFigure);
            app.dice2_5.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice5.png');
            app.dice2_6 = uiimage(app.UIFigure);
            app.dice2_6.ImageSource = fullfile(pathToMLAPP, 'diceImages', 'dice6.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testV8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end